classdef verifyPositionerSettingsConeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        sideView        matlab.ui.control.UIAxes
        aboveView       matlab.ui.control.UIAxes
        OnlyselectcontinueafteryouhaveverifiedLabel  matlab.ui.control.Label
        Label           matlab.ui.control.Label
        YouwillberesponsibleforanydamageLabel  matlab.ui.control.Label
        ContinueButton  matlab.ui.control.Button
        CancelButton    matlab.ui.control.Button
        Label_2         matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load tmpPositionFile
            %% Plot Location for side view
            txLength = (upDown.highLimit-upDown.lowLimit)/4;
            hold(app.sideView,'on');
            fill(app.sideView,[-txLength,0,0,-txLength],[txLength/8,txLength/8,-txLength/8,-txLength/8],[1,215/255,0])
            fill(app.sideView,[leftRight.loc,leftRight.loc-Tx.coneEdge,leftRight.loc,leftRight.loc+txLength,leftRight.loc+txLength],...
                [-txLength/2+upDown.loc,upDown.loc,txLength/2+upDown.loc,txLength/2+upDown.loc,-txLength/2+upDown.loc],[0,0.4470,0.7410])
            
            % Plot limits
            plot(app.sideView,[leftRight.lowLimit-Tx.coneEdge,leftRight.highLimit],[upDown.lowLimit,upDown.lowLimit],'k--','linewidth',3)
            plot(app.sideView,[leftRight.lowLimit-Tx.coneEdge,leftRight.highLimit],[upDown.highLimit,upDown.highLimit],'k--','linewidth',3)
            
            plot(app.sideView,[leftRight.lowLimit-Tx.coneEdge,leftRight.lowLimit-Tx.coneEdge],[upDown.lowLimit,upDown.highLimit],'k--','linewidth',3)
            plot(app.sideView,[leftRight.highLimit,leftRight.highLimit],[upDown.lowLimit,upDown.highLimit],'k--','linewidth',3)
            
            text(app.sideView,leftRight.lowLimit,upDown.lowLimit+txLength/2,['Distance between cone ', char(10), 'and H is ', num2str(leftRight.loc-Tx.coneEdge)],'fontsize',14)
            
            %% Plot Location for top-down view
            txLength = (frontBack.highLimit-frontBack.lowLimit)/4;
            hold(app.aboveView,'on');
            fill(app.aboveView,[-txLength,0,0,-txLength],[txLength/8,txLength/8,-txLength/8,-txLength/8],[1,215/255,0])
            fill(app.aboveView,[leftRight.loc,leftRight.loc-Tx.coneEdge,leftRight.loc,leftRight.loc+txLength,leftRight.loc+txLength],...
                [-txLength/2+frontBack.loc,frontBack.loc,txLength/2+frontBack.loc,txLength/2+frontBack.loc,-txLength/2+frontBack.loc],[0,0.4470,0.7410])
            
            % Plot limits
            plot(app.aboveView,[leftRight.lowLimit-Tx.coneEdge,leftRight.highLimit],[frontBack.lowLimit,frontBack.lowLimit],'k--','linewidth',3)
            plot(app.aboveView,[leftRight.lowLimit-Tx.coneEdge,leftRight.highLimit],[frontBack.highLimit,frontBack.highLimit],'k--','linewidth',3)
            
            plot(app.aboveView,[leftRight.lowLimit-Tx.coneEdge,leftRight.lowLimit-Tx.coneEdge],[frontBack.lowLimit,frontBack.highLimit],'k--','linewidth',3)
            plot(app.aboveView,[leftRight.highLimit,leftRight.highLimit],[frontBack.lowLimit,frontBack.highLimit],'k--','linewidth',3)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            VERIFIED = 1;
            tm = now;
            save tmpPositionFile VERIFIED tm;
            UIFigureCloseRequest(app,event);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            VERIFIED = 0;
            tm = now;
            save tmpPositionFile VERIFIED tm;
            UIFigureCloseRequest(app,event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.sideView = uiaxes(app.UIFigure);
            title(app.sideView, 'View From The Side')
            xlabel(app.sideView, 'Left/Right (mm)')
            ylabel(app.sideView, 'Up/Down (mm)')
            app.aboveView = uiaxes(app.UIFigure);
            title(app.aboveView, 'View From Above')
            xlabel(app.aboveView, 'Left/Right (mm)')
            ylabel(app.aboveView, 'Front/Back (mm)')
            app.OnlyselectcontinueafteryouhaveverifiedLabel = uilabel(app.UIFigure);
            app.OnlyselectcontinueafteryouhaveverifiedLabel.Text = {'Only select continue after you have verified:'; '    - The positions of the hydrophone and transducer relative to each other'; '    - That the software limits (dotted lines) are sufficient to prevent a collision between'; '       hydrophone and transducer'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Figures assume that the hydrophone is static and located at the '; 'origin of the positioner coordinate system'};
            app.YouwillberesponsibleforanydamageLabel = uilabel(app.UIFigure);
            app.YouwillberesponsibleforanydamageLabel.Text = 'You will be responsible for any damage!';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Note: The dotted lines show the limits for the estimated edge of the cone'; 'not for the edge of the transducer!'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = verifyPositionerSettingsConeGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end