classdef orthogonalTemperatureViewsGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DenoiseLabel                  matlab.ui.control.Label
        deNoise                       matlab.ui.control.CheckBox
        RightLGNLabel                 matlab.ui.control.Label
        showRightLgn                  matlab.ui.control.CheckBox
        LeftLGNLabel                  matlab.ui.control.Label
        showLeftLgn                   matlab.ui.control.CheckBox
        fileName                      matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        ShowMaxVoxelLabel             matlab.ui.control.Label
        showMaxVoxel                  matlab.ui.control.CheckBox
        ShowFocusLabel                matlab.ui.control.Label
        showFocus                     matlab.ui.control.CheckBox
        ShowTargetsLabel              matlab.ui.control.Label
        showTargets                   matlab.ui.control.CheckBox
        ShowFocusButton               matlab.ui.control.Button
        FindMaxLabel                  matlab.ui.control.Label
        findMax                       matlab.ui.control.CheckBox
        PowerBeamWidthEditField       matlab.ui.control.NumericEditField
        PowerBeamWidthEditFieldLabel  matlab.ui.control.Label
        CurrentValueLabel             matlab.ui.control.Label
        dynamicDown                   matlab.ui.control.Button
        DynamicLabel                  matlab.ui.control.Label
        dynamicUp                     matlab.ui.control.Button
        PlotOverTimeButton            matlab.ui.control.Button
        FocusLabel                    matlab.ui.control.Label
        expandFocus                   matlab.ui.control.Button
        axSlider                      matlab.ui.control.Slider
        sagSlider                     matlab.ui.control.Slider
        corSlider                     matlab.ui.control.Slider
        axRight                       matlab.ui.control.Button
        axLeft                        matlab.ui.control.Button
        sagRight                      matlab.ui.control.Button
        sagLeft                       matlab.ui.control.Button
        corRight                      matlab.ui.control.Button
        corLeft                       matlab.ui.control.Button
        windowDown                    matlab.ui.control.Button
        MaxTLabel                     matlab.ui.control.Label
        windowUp                      matlab.ui.control.Button
        tAxes                         matlab.ui.control.UIAxes
        tTime                         matlab.ui.control.UIAxes
        ax                            matlab.ui.control.UIAxes
        sag                           matlab.ui.control.UIAxes
        cor                           matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function drawMrImages(app)
            tic
            global sys
            if ~isfield(sys,'colorTempImg')
                error('You must create the interpolated thermometry image and store is in sys.colorTempImg.')
            end
            img = sys.colorTempImg;
            xyz = sys.xyzAxes;
            dynamic = sys.dynamic;
           
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
           
            % Create slice images with white line to show where other slices are
           
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            corImg(:,sagSlice,1) = 1;
            corImg(:,sagSlice,2) = 1;
            corImg(:,sagSlice,3) = 1;
            corImg(axSlice,:,1) = 1;
            corImg(axSlice,:,2) = 1;
            corImg(axSlice,:,3) = 1;
            
            showLeft = app.showLeftLgn.Value;
            showRight = app.showRightLgn.Value;
            if showLeft
                corImg = drawRoiEdges(corImg,squeeze(sys.leftLgnRoi(:,corSlice,:))');
            end
            if showRight
                corImg = drawRoiEdges(corImg,squeeze(sys.rightLgnRoi(:,corSlice,:))');
            end

            % Sagital
            sagImg = squeeze(img(sagSlice,:,:,:));
            sagImg = permute(sagImg,[2,1,3]);
            sagImg(:,corSlice,1) = 1;
            sagImg(:,corSlice,2) = 1;
            sagImg(:,corSlice,3) = 1;
            sagImg(axSlice,:,1) = 1;
            sagImg(axSlice,:,2) = 1;
            sagImg(axSlice,:,3) = 1;
            
            if showLeft
                sagImg = drawRoiEdges(sagImg,squeeze(sys.leftLgnRoi(sagSlice,:,:))');
            end
            if showRight
                sagImg = drawRoiEdges(sagImg,squeeze(sys.rightLgnRoi(sagSlice,:,:))');
            end
           
            % Axial
            axImg = squeeze(img(:,:,axSlice,:));
            axImg = permute(axImg,[2,1,3]);
            axImg(corSlice,:,1) = 1;
            axImg(corSlice,:,2) = 1;
            axImg(corSlice,:,3) = 1;
            axImg(:,sagSlice,1) = 1;
            axImg(:,sagSlice,2) = 1;
            axImg(:,sagSlice,3) = 1;
            
            if showLeft
                axImg = drawRoiEdges(axImg,squeeze(sys.leftLgnRoi(:,:,axSlice))');
            end
            if showRight
                axImg = drawRoiEdges(axImg,squeeze(sys.rightLgnRoi(:,:,axSlice))');
            end
            
            toc
            imshow(corImg,'xData',xyz(1,:),'yData',xyz(3,:),'Parent',app.cor,'InitialMagnification','fit');
            toc
            imshow(sagImg,'xData',xyz(2,:),'yData',xyz(3,:),'parent',app.sag,'InitialMagnification','fit');
            toc
            imshow(axImg,'xData',xyz(1,:),'yData',xyz(2,:),'parent',app.ax,'InitialMagnification','fit');
            toc
           
            cla(app.tTime);
            cla(app.tAxes);
            drawnow
           
            % Reset focus label based on current slices
            focus = [sys.ux(sagSlice),sys.uy(corSlice),sys.uz(axSlice)];
            app.FocusLabel.Text = ['Focus: ', num2str((focus)*1e3,3)];
            app.CurrentValueLabel.Text = ['Current Value: ', num2str(sys.tInterp(sagSlice,corSlice,axSlice,sys.dynamic)), ' C'];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, incomingSys)
            global sys
            sys = incomingSys;
            sys.window = [0,max(sys.aImg(:))];
            if ~isfield(sys,'rightLgnRoi')
                sys.rightLgnRoi = false(size(sys.aImg));
            end
            if ~isfield(sys,'leftLgnRoi')
                sys.leftLgnRoi = false(size(sys.aImg));
            end
            img = sys.aImg;
            
            % Setup initial values: Slice sliders
            app.corSlider.Limits = [1,size(img,2)];
            app.corSlider.Value = sys.focalSpotIdx(2);
            
            app.sagSlider.Limits = [1,size(img,1)];
            app.sagSlider.Value = sys.focalSpotIdx(1);
            
            app.axSlider.Limits = [1,size(img,3)];
            app.axSlider.Value = sys.focalSpotIdx(3);
            
            % Setup initial values: Window
            app.MaxTLabel.Text = ['MaxT:',num2str(sys.tWindow(2))];
            app.DynamicLabel.Text = ['Dynamic:',num2str(sys.dynamic)];
            
            app.fileName.Value = ['sonication',num2str(sys.curSonicationNo)];
            set(app.deNoise,'Value',1);
            drawMrImages(app);
        end

        % Button pushed function: windowUp
        function windowUpButtonPushed(app, event)
           global sys;
                       
            sys.tWindow(2) = sys.tWindow(2)+1;
            app.MaxTLabel.Text = ['MaxT:',num2str(sys.tWindow(2))];
            sys = draw3dTempOverlay(sys,sys.tWindow,sys.dynamic,1);
            drawMrImages(app);
        end

        % Button pushed function: windowDown
        function windowDownButtonPushed(app, event)
            global sys
            if sys.tWindow(2) <= 1
                return
            end
           
            sys.tWindow(2) = sys.tWindow(2)-1;
            app.MaxTLabel.Text = ['MaxT:',num2str(sys.tWindow(2))];
            sys = draw3dTempOverlay(sys,sys.tWindow,sys.dynamic,1);
            drawMrImages(app);
        end

        % Button pushed function: corLeft
        function corLeftButtonPushed(app, event)
            if app.corSlider.Value<=app.corSlider.Limits(1)
                return
            else
                app.corSlider.Value = app.corSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: corRight
        function corRightButtonPushed(app, event)
            if app.corSlider.Value>=app.corSlider.Limits(2)
                return
            else
                app.corSlider.Value = app.corSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            if app.sagSlider.Value<=app.sagSlider.Limits(1)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            if app.sagSlider.Value>=app.sagSlider.Limits(2)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axLeft
        function axLeftButtonPushed(app, event)
            if app.axSlider.Value<=app.axSlider.Limits(1)
                return
            else
                app.axSlider.Value = app.axSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axRight
        function axRightButtonPushed(app, event)
            if app.axSlider.Value>=app.axSlider.Limits(2)
                return
            else
                app.axSlider.Value = app.axSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Callback function
        function doneButtonPushed(app, event)
            focalSpotIdx = [app.sagSlider.Value,app.corSlider.Value,app.axSlider.Value];
            save('guiFileOutput','focalSpotIdx');
            closereq();
        end

        % Button pushed function: expandFocus
        function expandFocusButtonPushed(app, event)
            global sys;
            xyz = sys.xyzAxes;
            img = sys.aImg;
            
            img = sys.colorTempImg;
            xyz = sys.xyzAxes;
            dynamic = sys.dynamic;
           
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            % Sagital
            sagImg = squeeze(img(sagSlice,:,:,:));
            sagImg = permute(sagImg,[2,1,3]);
            % Axial
            axImg = squeeze(img(:,:,axSlice,:));
            axImg = permute(axImg,[2,1,3]);
            
            mkSize = 4;
            lnWd = 0.4;
            
            h1 = figure(1);
            clf;
            h1.InvertHardcopy = 'off';
            ax1 = gca;
            imshow(corImg,'xData',sys.ux,'yData',sys.uz,'Parent',ax1,'InitialMagnification','fit');
            hold(ax1,'on')
            plot(sys.focalSpot(1)*1e-3,sys.focalSpot(3)*1e-3,'wx','linewidth',lnWd,'markersize',mkSize)
            makeFigureBig(h1,18,18,'k');
            
            h2 = figure(2);
            clf;
            h2.InvertHardcopy = 'off';
            ax2 = gca;
            imshow(sagImg,'xData',sys.uy,'yData',sys.uz,'parent',ax2,'InitialMagnification','fit');
            hold(ax2,'on')
            plot(sys.focalSpot(2)*1e-3,sys.focalSpot(3)*1e-3,'wx','linewidth',lnWd,'markersize',mkSize)
            makeFigureBig(h2,18,18,'k');
            
            h3 = figure(3);
            clf;
            h3.InvertHardcopy = 'off';
            ax3 = gca;
            imshow(axImg,'xData',sys.ux,'yData',sys.uy,'parent',ax3,'InitialMagnification','fit');
            hold(ax3,'on')
            plot(sys.focalSpot(1)*1e-3,sys.focalSpot(2)*1e-3,'wx','linewidth',lnWd,'markersize',mkSize)
            makeFigureBig(h3,18,18,'k');
            
        end

        % Value changed function: corSlider
        function corSliderValueChanged(app, event)
            value = app.corSlider.Value;
            app.corSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            value = app.sagSlider.Value;
            app.sagSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: axSlider
        function axSliderValueChanged(app, event)
            value = app.axSlider.Value;
            app.axSlider.Value = round(value);
            drawMrImages(app);
        end

        % Button pushed function: PlotOverTimeButton
        function PlotOverTimeButtonPushed(app, event)
            global sys
            tImg = sys.tInterp;
            
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            % Select an ROI
            dx = abs(sys.ax(2)-sys.ax(1));
            dy = abs(sys.ay(2)-sys.ay(1));
            dz = abs(sys.az(2)-sys.az(1));
            roiRadius = 5e-3;
            curCenter = [sagSlice,corSlice,axSlice];
            xIdx = (curCenter(1)-ceil(roiRadius/dx)):(curCenter(1)+ceil(roiRadius/dx));
            yIdx = (curCenter(2)-ceil(roiRadius/dy)):(curCenter(2)+ceil(roiRadius/dy));
            zIdx = (curCenter(3)-ceil(roiRadius/dz)):(curCenter(3)+ceil(roiRadius/dz));
            
            x = sys.ax - sys.ax(curCenter(1));
            y = sys.ay - sys.ay(curCenter(2));
            z = sys.az - sys.az(curCenter(3));
            
            tTimeAvg = zeros(1,size(tImg,4));
            tTimeMax = tTimeAvg;
            for ii = 1:size(tImg,4)
                curT = tImg(xIdx,yIdx,zIdx,ii);
                tTimeAvg(ii) = mean(curT(:));
%                 tTimeMax(ii) = max(curT(:));
                tTimeMax(ii) = tImg(sagSlice,corSlice,axSlice,ii);
            end
            
            cla(app.tTime);
            timeAxis = (0:(size(tImg,4)-1))*sys.dynamicLength;
            duration = sys.sonication(sys.curSonication).duration;
            firstDynamic = sys.sonication(sys.curSonication).firstDynamic;
            usTime = [(firstDynamic-1)*sys.dynamicLength,(firstDynamic-1)*sys.dynamicLength+duration];
            plot(app.tTime,timeAxis,tTimeAvg,'-',timeAxis,tTimeMax,'-','linewidth',2);
            cur_yAx = app.tTime.YLim;
            hold(app.tTime,'on');
            plot(app.tTime,ones(1,2)*usTime(1),cur_yAx,'k--',ones(1,2)*usTime(2),cur_yAx,'k--','linewidth',1)
            legend(app.tTime,'Average','Max','US On');
            grid on;
            
            plot(app.tAxes,x*1e3,tImg(:,corSlice,axSlice,sys.dynamic),'-',...
                y*1e3,tImg(sagSlice,:,axSlice,sys.dynamic),'-',...
                z*1e3,squeeze(tImg(sagSlice,corSlice,:,sys.dynamic)),'-','linewidth',2)
            legend(app.tAxes,'x','y','z')
            axis(app.tAxes,[-3,3,0,sys.tWindow(2)]);
            grid on
            
            clear roi X Y Z x y z R
            
        end

        % Button pushed function: dynamicUp
        function dynamicUpButtonPushed(app, event)
            global sys
            if sys.dynamic >= size(sys.tInterp,4)
                sys.dynamic = size(sys.tInterp,4);
            else
                sys.dynamic = sys.dynamic+1;
            end
            app.DynamicLabel.Text = ['Dynamic:',num2str(sys.dynamic)];
            
            sys = draw3dTempOverlay(sys,sys.tWindow,sys.dynamic,1);
            
            drawMrImages(app);
        end

        % Button pushed function: dynamicDown
        function dynamicDownButtonPushed(app, event)
            global sys
            if sys.dynamic <= 1
                sys.dynamic = 1;
            else
                sys.dynamic = sys.dynamic-1;
            end
            app.DynamicLabel.Text = ['Dynamic:',num2str(sys.dynamic)];
            
            sys = draw3dTempOverlay(sys,sys.tWindow,sys.dynamic,1);
            
            drawMrImages(app);
        end

        % Button pushed function: ShowFocusButton
        function ShowFocusButtonPushed(app, event)
            global sys;
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            flags = [app.showFocus.Value,app.showMaxVoxel.Value,app.showTargets.Value,app.findMax.Value,app.showLeftLgn.Value,app.showRightLgn.Value,app.deNoise.Value];
            pbw = app.PowerBeamWidthEditField.Value;
            showActualFocus(sys,sys.curSonicationNo,[sagSlice,corSlice,axSlice],sys.dynamic,pbw,flags,[sys.expPath,'Results\',app.fileName.Value]);
        end

        % Value changed function: showRightLgn
        function showRightLgnValueChanged(app, event)
            drawMrImages(app);
            
        end

        % Value changed function: showLeftLgn
        function showLeftLgnValueChanged(app, event)
            drawMrImages(app);
        end

        % Value changed function: deNoise
        function deNoiseValueChanged(app, event)
            global sys;
            value = app.deNoise.Value;
            sys = draw3dTempOverlay(sys,[0,sys.tWindow(2)],sys.dynamic,1,value);
            drawMrImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.cor = uiaxes(app.UIFigure);
            title(app.cor, 'Coronal')
            app.sag = uiaxes(app.UIFigure);
            title(app.sag, 'Sagital')
            app.ax = uiaxes(app.UIFigure);
            title(app.ax, 'Axial')
            app.tTime = uiaxes(app.UIFigure);
            title(app.tTime, 'Temperature over time')
            xlabel(app.tTime, 'Dynamic')
            ylabel(app.tTime, 'Temperature (C)')
            app.tAxes = uiaxes(app.UIFigure);
            title(app.tAxes, 'Cut along axes')
            xlabel(app.tAxes, 'Distance from center (mm)')
            ylabel(app.tAxes, 'Temperature (C)')
            app.windowUp = uibutton(app.UIFigure, 'push');
            app.windowUp.ButtonPushedFcn = createCallbackFcn(app, @windowUpButtonPushed, true);
            app.windowUp.Text = '^';
            app.MaxTLabel = uilabel(app.UIFigure);
            app.MaxTLabel.Text = 'MaxT';
            app.windowDown = uibutton(app.UIFigure, 'push');
            app.windowDown.ButtonPushedFcn = createCallbackFcn(app, @windowDownButtonPushed, true);
            app.windowDown.Text = 'v';
            app.corLeft = uibutton(app.UIFigure, 'push');
            app.corLeft.ButtonPushedFcn = createCallbackFcn(app, @corLeftButtonPushed, true);
            app.corLeft.Text = '<';
            app.corRight = uibutton(app.UIFigure, 'push');
            app.corRight.ButtonPushedFcn = createCallbackFcn(app, @corRightButtonPushed, true);
            app.corRight.Text = '>';
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.axLeft = uibutton(app.UIFigure, 'push');
            app.axLeft.ButtonPushedFcn = createCallbackFcn(app, @axLeftButtonPushed, true);
            app.axLeft.Text = '<';
            app.axRight = uibutton(app.UIFigure, 'push');
            app.axRight.ButtonPushedFcn = createCallbackFcn(app, @axRightButtonPushed, true);
            app.axRight.Text = '>';
            app.corSlider = uislider(app.UIFigure);
            app.corSlider.MajorTicks = [];
            app.corSlider.MajorTickLabels = {};
            app.corSlider.ValueChangedFcn = createCallbackFcn(app, @corSliderValueChanged, true);
            app.corSlider.MinorTicks = [];
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.MajorTickLabels = {};
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.axSlider = uislider(app.UIFigure);
            app.axSlider.MajorTicks = [];
            app.axSlider.MajorTickLabels = {};
            app.axSlider.ValueChangedFcn = createCallbackFcn(app, @axSliderValueChanged, true);
            app.axSlider.MinorTicks = [];
            app.expandFocus = uibutton(app.UIFigure, 'push');
            app.expandFocus.ButtonPushedFcn = createCallbackFcn(app, @expandFocusButtonPushed, true);
            app.expandFocus.Text = 'Expand Image';
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus:';
            app.PlotOverTimeButton = uibutton(app.UIFigure, 'push');
            app.PlotOverTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOverTimeButtonPushed, true);
            app.PlotOverTimeButton.Text = 'Plot Over Time';
            app.dynamicUp = uibutton(app.UIFigure, 'push');
            app.dynamicUp.ButtonPushedFcn = createCallbackFcn(app, @dynamicUpButtonPushed, true);
            app.dynamicUp.Text = '^';
            app.DynamicLabel = uilabel(app.UIFigure);
            app.DynamicLabel.Text = 'Dynamic';
            app.dynamicDown = uibutton(app.UIFigure, 'push');
            app.dynamicDown.ButtonPushedFcn = createCallbackFcn(app, @dynamicDownButtonPushed, true);
            app.dynamicDown.Text = 'v';
            app.CurrentValueLabel = uilabel(app.UIFigure);
            app.CurrentValueLabel.Text = 'Current Value: ';
            app.PowerBeamWidthEditFieldLabel = uilabel(app.UIFigure);
            app.PowerBeamWidthEditFieldLabel.Text = 'Power Beam Width';
            app.PowerBeamWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.findMax = uicheckbox(app.UIFigure);
            app.findMax.Text = '';
            app.FindMaxLabel = uilabel(app.UIFigure);
            app.FindMaxLabel.Text = 'Find Max';
            app.ShowFocusButton = uibutton(app.UIFigure, 'push');
            app.ShowFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFocusButtonPushed, true);
            app.ShowFocusButton.Text = 'Show Focus';
            app.showTargets = uicheckbox(app.UIFigure);
            app.showTargets.Text = '';
            app.ShowTargetsLabel = uilabel(app.UIFigure);
            app.ShowTargetsLabel.Text = 'Show Targets';
            app.showFocus = uicheckbox(app.UIFigure);
            app.showFocus.Text = '';
            app.ShowFocusLabel = uilabel(app.UIFigure);
            app.ShowFocusLabel.Text = 'Show Focus';
            app.showMaxVoxel = uicheckbox(app.UIFigure);
            app.showMaxVoxel.Text = '';
            app.ShowMaxVoxelLabel = uilabel(app.UIFigure);
            app.ShowMaxVoxelLabel.Text = 'Show Max Voxel';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.fileName = uieditfield(app.UIFigure, 'text');
            app.showLeftLgn = uicheckbox(app.UIFigure);
            app.showLeftLgn.ValueChangedFcn = createCallbackFcn(app, @showLeftLgnValueChanged, true);
            app.showLeftLgn.Text = '';
            app.LeftLGNLabel = uilabel(app.UIFigure);
            app.LeftLGNLabel.Text = 'Left LGN';
            app.showRightLgn = uicheckbox(app.UIFigure);
            app.showRightLgn.ValueChangedFcn = createCallbackFcn(app, @showRightLgnValueChanged, true);
            app.showRightLgn.Text = '';
            app.RightLGNLabel = uilabel(app.UIFigure);
            app.RightLGNLabel.Text = 'Right LGN';
            app.deNoise = uicheckbox(app.UIFigure);
            app.deNoise.ValueChangedFcn = createCallbackFcn(app, @deNoiseValueChanged, true);
            app.deNoise.Text = '';
            app.DenoiseLabel = uilabel(app.UIFigure);
            app.DenoiseLabel.Text = 'Denoise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orthogonalTemperatureViewsGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end