classdef segmentLgnGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        cor                   matlab.ui.control.UIAxes
        sag                   matlab.ui.control.UIAxes
        ax                    matlab.ui.control.UIAxes
        windowUp              matlab.ui.control.Button
        WindowLabel           matlab.ui.control.Label
        windowDown            matlab.ui.control.Button
        corLeft               matlab.ui.control.Button
        corRight              matlab.ui.control.Button
        sagLeft               matlab.ui.control.Button
        sagRight              matlab.ui.control.Button
        axLeft                matlab.ui.control.Button
        axRight               matlab.ui.control.Button
        corSlider             matlab.ui.control.Slider
        sagSlider             matlab.ui.control.Slider
        axSlider              matlab.ui.control.Slider
        segmentLeftLgn        matlab.ui.control.Button
        done                  matlab.ui.control.Button
        FocusLabel            matlab.ui.control.Label
        segmentRightLgn       matlab.ui.control.Button
        ShowLeftLGNCheckBox   matlab.ui.control.CheckBox
        ShowRightLGNCheckBox  matlab.ui.control.CheckBox
    end


    methods (Access = private)
    
        function drawMrImages(app)
            global sys
            img = sys.colorImg;
            xyz = sys.xyzAxes;            
            
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            corImg(:,sagSlice,1) = 255;
            corImg(:,sagSlice,2) = 255;
            corImg(:,sagSlice,3) = 255;
            
            corImg(axSlice,:,1) = 255;
            corImg(axSlice,:,2) = 255;
            corImg(axSlice,:,3) = 255;
            
            showLeft = app.ShowLeftLGNCheckBox.Value;
            showRight = app.ShowRightLGNCheckBox.Value;
            if showLeft
                corImg = drawRoiEdges(corImg,squeeze(sys.leftLgnRoi(:,corSlice,:))');
            end
            if showRight
                corImg = drawRoiEdges(corImg,squeeze(sys.rightLgnRoi(:,corSlice,:))');
            end

            % Sagital
            sagImg = squeeze(img(sagSlice,:,:,:));
            sagImg = permute(sagImg,[2,1,3]);
            sagImg(:,corSlice,1) = 255;
            sagImg(:,corSlice,2) = 255;
            sagImg(:,corSlice,3) = 255;
            
            sagImg(axSlice,:,1) = 255;
            sagImg(axSlice,:,2) = 255;
            sagImg(axSlice,:,3) = 255;
            
            if showLeft
                sagImg = drawRoiEdges(sagImg,squeeze(sys.leftLgnRoi(sagSlice,:,:))');
            end
            if showRight
                sagImg = drawRoiEdges(sagImg,squeeze(sys.rightLgnRoi(sagSlice,:,:))');
            end
            
            % Axial
            axImg = squeeze(img(:,:,axSlice,:));
            axImg = permute(axImg,[2,1,3]);
            axImg(corSlice,:,1) = 255;
            axImg(corSlice,:,2) = 255;
            axImg(corSlice,:,3) = 255;
            
            axImg(:,sagSlice,1) = 255;
            axImg(:,sagSlice,2) = 255;
            axImg(:,sagSlice,3) = 255;
            
            if showLeft
                axImg = drawRoiEdges(axImg,squeeze(sys.leftLgnRoi(:,:,axSlice))');
            end
            if showRight
                axImg = drawRoiEdges(axImg,squeeze(sys.rightLgnRoi(:,:,axSlice))');
            end
            
            imshow(corImg,'xdata',xyz(1,:),'ydata',xyz(3,:),'Parent',app.cor);
            imshow(sagImg,'xdata',xyz(2,:),'ydata',xyz(3,:),'Parent',app.sag);
            imshow(axImg,'xdata',xyz(1,:),'ydata',xyz(2,:),'Parent',app.ax);
            
            % Reset focus label based on current slices
            txCenter = [sys.ux(sys.txCenterIdx(1)),sys.uy(sys.txCenterIdx(2)),sys.uz(sys.txCenterIdx(3))];
            focus = [sys.ux(sagSlice),sys.uy(corSlice),sys.uz(axSlice)];
            app.FocusLabel.Text = ['Focus: ', num2str((focus-txCenter)*1e3,3)];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, incomingSys)
            global sys
            sys = incomingSys;
            sys.window = [0,max(sys.aImg(:))];
            img = sys.colorImg;
            
            sys.leftLgnRoi = false(size(sys.aImg));
            sys.rightLgnRoi = false(size(sys.aImg));
            
            % Setup initial values: Slice sliders
            app.corSlider.Limits = [1,size(img,2)];
            app.corSlider.Value = ceil(size(img,2)/2);
            
            app.sagSlider.Limits = [1,size(img,1)];
            app.sagSlider.Value = ceil(size(img,1)/2);
            
            app.axSlider.Limits = [1,size(img,3)];
            app.axSlider.Value = ceil(size(img,3)/2);
            
            % Setup initial values: Window
            
            drawMrImages(app);
        end

        % Button pushed function: windowUp
        function windowUpButtonPushed(app, event)
            global sys;
            
            if sys.window(2) >= max(sys.aImg(:))
                return
            end
            
            sys.window(2) = sys.window(2)+50;
            sys.colorImg = drawTransducerColor(sys.aImg,sys.txImg,sys.window);
            
            drawMrImages(app);
        end

        % Button pushed function: windowDown
        function windowDownButtonPushed(app, event)
            global sys
            if sys.window(2) <= 0
                return
            end
            
            sys.window(2) = sys.window(2)-50;
            sys.colorImg = drawTransducerColor(sys.aImg,sys.txImg,sys.window);
            
            drawMrImages(app);
        end

        % Button pushed function: corLeft
        function corLeftButtonPushed(app, event)
            if app.corSlider.Value<=app.corSlider.Limits(1)
                return
            else
                app.corSlider.Value = app.corSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: corRight
        function corRightButtonPushed(app, event)
            if app.corSlider.Value>=app.corSlider.Limits(2)
                return
            else
                app.corSlider.Value = app.corSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            if app.sagSlider.Value<=app.sagSlider.Limits(1)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            if app.sagSlider.Value>=app.sagSlider.Limits(2)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axLeft
        function axLeftButtonPushed(app, event)
            if app.axSlider.Value<=app.axSlider.Limits(1)
                return
            else
                app.axSlider.Value = app.axSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axRight
        function axRightButtonPushed(app, event)
            if app.axSlider.Value>=app.axSlider.Limits(2)
                return
            else
                app.axSlider.Value = app.axSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: done
        function doneButtonPushed(app, event)
            global sys
            focalSpotIdx = [app.sagSlider.Value,app.corSlider.Value,app.axSlider.Value];
            leftLgnRoi = sys.leftLgnRoi;
            rightLgnRoi = sys.rightLgnRoi;
            save('guiFileOutput','focalSpotIdx','rightLgnRoi','leftLgnRoi');
            closereq();
        end

        % Button pushed function: segmentLeftLgn
        function segmentLeftLgnButtonPushed(app, event)
            global sys;
            xyz = sys.xyzAxes;
            img = sys.colorImg;
            
            h = figure;
            corSlice = app.corSlider.Value;
            curSlice = sys.leftLgnRoi(:,corSlice,:);
            if sum(curSlice(:))>0
                answer = questdlg('A segmentation of the left LGN exists for this slice. Replace?');
                switch answer
                    case 'Yes'
                    case 'No'
                        return
                    case 'Cancel'
                        return
                end
            end
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            
            imshow(corImg,'xdata',xyz(1,:),'ydata',xyz(3,:));
            roi = segmentLgnSlice(h);
            sys.leftLgnRoi(:,corSlice,:) = roi;
            
            drawMrImages(app);
        end

        % Value changed function: corSlider
        function corSliderValueChanged(app, event)
            value = app.corSlider.Value;
            app.corSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            value = app.sagSlider.Value;
            app.sagSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: axSlider
        function axSliderValueChanged(app, event)
            value = app.axSlider.Value;
            app.axSlider.Value = round(value);
            drawMrImages(app);
        end

        % Button pushed function: segmentRightLgn
        function segmentRightLgnButtonPushed(app, event)
            global sys;
            xyz = sys.xyzAxes;
            img = sys.colorImg;
            
            h = figure;
            corSlice = app.corSlider.Value;
            curSlice = sys.rightLgnRoi(:,corSlice,:);
            if sum(curSlice(:))>0
                answer = questdlg('A segmentation of the right LGN exists for this slice. Replace?');
                switch answer
                    case 'Yes'
                    case 'No'
                        return
                    case 'Cancel'
                        return
                end
            end
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            
            imshow(corImg,'xdata',xyz(1,:),'ydata',xyz(3,:));
            roi = segmentLgnSlice(h);
            sys.rightLgnRoi(:,corSlice,:) = roi;
            
            drawMrImages(app);
        end

        % Value changed function: ShowRightLGNCheckBox
        function ShowRightLGNCheckBoxValueChanged(app, event)
            drawMrImages(app);
        end

        % Value changed function: ShowLeftLGNCheckBox
        function ShowLeftLGNCheckBoxValueChanged(app, event)
            drawMrImages(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.cor = uiaxes(app.UIFigure);
            title(app.cor, 'Coronal')
            app.sag = uiaxes(app.UIFigure);
            title(app.sag, 'Sagital')
            app.ax = uiaxes(app.UIFigure);
            title(app.ax, 'Axial')
            app.windowUp = uibutton(app.UIFigure, 'push');
            app.windowUp.ButtonPushedFcn = createCallbackFcn(app, @windowUpButtonPushed, true);
            app.windowUp.Text = '^';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.windowDown = uibutton(app.UIFigure, 'push');
            app.windowDown.ButtonPushedFcn = createCallbackFcn(app, @windowDownButtonPushed, true);
            app.windowDown.Text = 'v';
            app.corLeft = uibutton(app.UIFigure, 'push');
            app.corLeft.ButtonPushedFcn = createCallbackFcn(app, @corLeftButtonPushed, true);
            app.corLeft.Text = '<';
            app.corRight = uibutton(app.UIFigure, 'push');
            app.corRight.ButtonPushedFcn = createCallbackFcn(app, @corRightButtonPushed, true);
            app.corRight.Text = '>';
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.axLeft = uibutton(app.UIFigure, 'push');
            app.axLeft.ButtonPushedFcn = createCallbackFcn(app, @axLeftButtonPushed, true);
            app.axLeft.Text = '<';
            app.axRight = uibutton(app.UIFigure, 'push');
            app.axRight.ButtonPushedFcn = createCallbackFcn(app, @axRightButtonPushed, true);
            app.axRight.Text = '>';
            app.corSlider = uislider(app.UIFigure);
            app.corSlider.MajorTicks = [];
            app.corSlider.MajorTickLabels = {};
            app.corSlider.ValueChangedFcn = createCallbackFcn(app, @corSliderValueChanged, true);
            app.corSlider.MinorTicks = [];
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.MajorTickLabels = {};
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.axSlider = uislider(app.UIFigure);
            app.axSlider.MajorTicks = [];
            app.axSlider.MajorTickLabels = {};
            app.axSlider.ValueChangedFcn = createCallbackFcn(app, @axSliderValueChanged, true);
            app.axSlider.MinorTicks = [];
            app.segmentLeftLgn = uibutton(app.UIFigure, 'push');
            app.segmentLeftLgn.ButtonPushedFcn = createCallbackFcn(app, @segmentLeftLgnButtonPushed, true);
            app.segmentLeftLgn.Text = 'Segment Left LGN';
            app.done = uibutton(app.UIFigure, 'push');
            app.done.ButtonPushedFcn = createCallbackFcn(app, @doneButtonPushed, true);
            app.done.Text = 'Done';
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus:';
            app.segmentRightLgn = uibutton(app.UIFigure, 'push');
            app.segmentRightLgn.ButtonPushedFcn = createCallbackFcn(app, @segmentRightLgnButtonPushed, true);
            app.segmentRightLgn.Text = 'Segment Reft LGN';
            app.ShowLeftLGNCheckBox = uicheckbox(app.UIFigure);
            app.ShowLeftLGNCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLeftLGNCheckBoxValueChanged, true);
            app.ShowLeftLGNCheckBox.Text = 'Show Left LGN';
            app.ShowRightLGNCheckBox = uicheckbox(app.UIFigure);
            app.ShowRightLGNCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRightLGNCheckBoxValueChanged, true);
            app.ShowRightLGNCheckBox.Text = 'Show Right LGN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentLgnGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end