classdef selectRegion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        cor              matlab.ui.control.UIAxes
        sag              matlab.ui.control.UIAxes
        ax               matlab.ui.control.UIAxes
        windowUp         matlab.ui.control.Button
        WindowLabel      matlab.ui.control.Label
        windowDown       matlab.ui.control.Button
        corLeft          matlab.ui.control.Button
        corRight         matlab.ui.control.Button
        sagLeft          matlab.ui.control.Button
        sagRight         matlab.ui.control.Button
        axLeft           matlab.ui.control.Button
        axRight          matlab.ui.control.Button
        corSlider        matlab.ui.control.Slider
        sagSlider        matlab.ui.control.Slider
        axSlider         matlab.ui.control.Slider
        selectFocus      matlab.ui.control.Button
        done             matlab.ui.control.Button
        FocusLabel       matlab.ui.control.Label
        dynamicDecrease  matlab.ui.control.Button
        dynamicLabel     matlab.ui.control.Label
        dynamicIncrease  matlab.ui.control.Button
    end


    methods (Access = private)
    
        function drawMrImages(app)
            global sys
            img = sys.img(:,:,:,sys.dynamic);
            xyz = sys.xyzAxes;            
            
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            corImg(:,sagSlice) = max(img(:));
            corImg(axSlice,:) = max(img(:));

            % Sagital
            sagImg = squeeze(img(sagSlice,:,:,:));
            sagImg = permute(sagImg,[2,1,3]);
            sagImg(:,corSlice) = max(img(:));
            sagImg(axSlice,:) = max(img(:));
            
            % Axial
            axImg = squeeze(img(:,:,axSlice,:));
            axImg = permute(axImg,[2,1,3]);
            axImg(corSlice,:) = max(img(:));
            axImg(:,sagSlice) = max(img(:));
            
            imshow(corImg,[sys.window],'xdata',xyz(1,:),'ydata',xyz(3,:),'Parent',app.cor);
            imshow(sagImg,[sys.window],'xdata',xyz(2,:),'ydata',xyz(3,:),'Parent',app.sag);
            imshow(axImg,[sys.window],'xdata',xyz(1,:),'ydata',xyz(2,:),'Parent',app.ax);
            
            % Reset TxCenter label based on current slices
            txCenter = [sys.x(sagSlice),sys.y(corSlice),sys.z(axSlice)];
            app.FocusLabel.Text = ['Tx Center: ', num2str((txCenter)*1e3,2)];
            keyboard
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, incomingSys)
            global sys
            sys = incomingSys;
            img = sys.img;
            if size(sys.img,4) > 5
                sys.dynamic = 5;
            else
                sys.dynamic = size(sys.img,4);
            end
            app.dynamicLabel.Text = ['Dynamic #: ', num2str(sys.dynamic)];
            % Setup initial values: Slice sliders
            app.corSlider.Limits = [1,size(img,2)];
            app.corSlider.Value = ceil(size(img,2)/2);
            
            app.sagSlider.Limits = [1,size(img,1)];
            app.sagSlider.Value = ceil(size(img,1)/2);
            
            app.axSlider.Limits = [1,size(img,3)];
            app.axSlider.Value = ceil(size(img,3)/2);
            
            % Setup initial values: Window
            drawMrImages(app);
        end

        % Button pushed function: windowUp
        function windowUpButtonPushed(app, event)
            global sys;
            
            if sys.window(2) >= max(sys.img(:))
                return
            end
            
            sys.window(2) = sys.window(2)+250;
            
            drawMrImages(app);
        end

        % Button pushed function: windowDown
        function windowDownButtonPushed(app, event)
            global sys
            if sys.window(2) <= 0
                return
            end
            
            sys.window(2) = sys.window(2)-250;
            
            drawMrImages(app);
        end

        % Button pushed function: corLeft
        function corLeftButtonPushed(app, event)
            if app.corSlider.Value<=app.corSlider.Limits(1)
                return
            else
                app.corSlider.Value = app.corSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: corRight
        function corRightButtonPushed(app, event)
            if app.corSlider.Value>=app.corSlider.Limits(2)
                return
            else
                app.corSlider.Value = app.corSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            if app.sagSlider.Value<=app.sagSlider.Limits(1)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            if app.sagSlider.Value>=app.sagSlider.Limits(2)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axLeft
        function axLeftButtonPushed(app, event)
            if app.axSlider.Value<=app.axSlider.Limits(1)
                return
            else
                app.axSlider.Value = app.axSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axRight
        function axRightButtonPushed(app, event)
            if app.axSlider.Value>=app.axSlider.Limits(2)
                return
            else
                app.axSlider.Value = app.axSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: done
        function doneButtonPushed(app, event)
            txCenter = [app.sagSlider.Value,app.corSlider.Value,app.axSlider.Value];
            save('guiFileOutput','txCenter');
            closereq();
        end

        % Button pushed function: selectFocus
        function selectFocusButtonPushed(app, event)
            global sys;
            xyz = sys.xyzAxes;
            img = sys.colorImg;
            
            h = figure;
            corSlice = app.corSlider.Value;
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            
            imshow(corImg,'xdata',xyz(1,:),'ydata',xyz(3,:));
            [focX,focZ] = ginput(1);
            
            [~,focX] = min(abs(focX-sys.x));
            [~,focZ] = min(abs(focZ-sys.x));
            
            app.sagSlider.Value = focX;
            app.axSlider.Value = focZ;
            close(h);
            
            drawMrImages(app);
        end

        % Value changed function: corSlider
        function corSliderValueChanged(app, event)
            value = app.corSlider.Value;
            app.corSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            value = app.sagSlider.Value;
            app.sagSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: axSlider
        function axSliderValueChanged(app, event)
            value = app.axSlider.Value;
            app.axSlider.Value = round(value);
            drawMrImages(app);
        end

        % Button pushed function: dynamicDecrease
        function dynamicDecreaseButtonPushed(app, event)
            global sys;
            if sys.dynamic > sys.baseline+1
                sys.dynamic = sys.dynamic - 1;
                app.dynamicLabel.Text = ['Dynamic #: ', num2str(sys.dynamic)];
                drawMrImages(app);
            else
                msgbox('Already at baseline + 1')
            end
        end

        % Button pushed function: dynamicIncrease
        function dynamicIncreaseButtonPushed(app, event)
            global sys;
            if sys.dynamic < size(sys.img,4)
                sys.dynamic = sys.dynamic + 1;
                app.dynamicLabel.Text = ['Dynamic #: ', num2str(sys.dynamic)];
                drawMrImages(app);
            else
                msgbox('Already at last dynamic')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.cor = uiaxes(app.UIFigure);
            title(app.cor, 'Coronal')
            app.sag = uiaxes(app.UIFigure);
            title(app.sag, 'Sagital')
            app.ax = uiaxes(app.UIFigure);
            title(app.ax, 'Axial')
            app.windowUp = uibutton(app.UIFigure, 'push');
            app.windowUp.ButtonPushedFcn = createCallbackFcn(app, @windowUpButtonPushed, true);
            app.windowUp.Text = '^';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.windowDown = uibutton(app.UIFigure, 'push');
            app.windowDown.ButtonPushedFcn = createCallbackFcn(app, @windowDownButtonPushed, true);
            app.windowDown.Text = 'v';
            app.corLeft = uibutton(app.UIFigure, 'push');
            app.corLeft.ButtonPushedFcn = createCallbackFcn(app, @corLeftButtonPushed, true);
            app.corLeft.Text = '<';
            app.corRight = uibutton(app.UIFigure, 'push');
            app.corRight.ButtonPushedFcn = createCallbackFcn(app, @corRightButtonPushed, true);
            app.corRight.Text = '>';
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.axLeft = uibutton(app.UIFigure, 'push');
            app.axLeft.ButtonPushedFcn = createCallbackFcn(app, @axLeftButtonPushed, true);
            app.axLeft.Text = '<';
            app.axRight = uibutton(app.UIFigure, 'push');
            app.axRight.ButtonPushedFcn = createCallbackFcn(app, @axRightButtonPushed, true);
            app.axRight.Text = '>';
            app.corSlider = uislider(app.UIFigure);
            app.corSlider.MajorTicks = [];
            app.corSlider.MajorTickLabels = {};
            app.corSlider.ValueChangedFcn = createCallbackFcn(app, @corSliderValueChanged, true);
            app.corSlider.MinorTicks = [];
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.MajorTickLabels = {};
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.axSlider = uislider(app.UIFigure);
            app.axSlider.MajorTicks = [];
            app.axSlider.MajorTickLabels = {};
            app.axSlider.ValueChangedFcn = createCallbackFcn(app, @axSliderValueChanged, true);
            app.axSlider.MinorTicks = [];
            app.selectFocus = uibutton(app.UIFigure, 'push');
            app.selectFocus.ButtonPushedFcn = createCallbackFcn(app, @selectFocusButtonPushed, true);
            app.selectFocus.Text = 'Select Focus';
            app.done = uibutton(app.UIFigure, 'push');
            app.done.ButtonPushedFcn = createCallbackFcn(app, @doneButtonPushed, true);
            app.done.Text = 'Done';
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus:';
            app.dynamicDecrease = uibutton(app.UIFigure, 'push');
            app.dynamicDecrease.ButtonPushedFcn = createCallbackFcn(app, @dynamicDecreaseButtonPushed, true);
            app.dynamicDecrease.Text = '<';
            app.dynamicLabel = uilabel(app.UIFigure);
            app.dynamicLabel.Text = '';
            app.dynamicIncrease = uibutton(app.UIFigure, 'push');
            app.dynamicIncrease.ButtonPushedFcn = createCallbackFcn(app, @dynamicIncreaseButtonPushed, true);
            app.dynamicIncrease.Text = '>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectRegion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end