classdef verifyPreTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        rawTraces                      matlab.ui.control.UIAxes
        bModeImage1                    matlab.ui.control.UIAxes
        distTrace1                     matlab.ui.control.UIAxes
        distTrace2                     matlab.ui.control.UIAxes
        distTrace3                     matlab.ui.control.UIAxes
        distTrace4                     matlab.ui.control.UIAxes
        distTrace5                     matlab.ui.control.UIAxes
        AcceptButton                   matlab.ui.control.Button
        ReScanButton                   matlab.ui.control.Button
        goldStandardFile               matlab.ui.control.EditField
        SelectGoldStandardButton       matlab.ui.control.Button
        bModeImage2                    matlab.ui.control.UIAxes
        bModeImage3                    matlab.ui.control.UIAxes
        bModeImage4                    matlab.ui.control.UIAxes
        bModeImage5                    matlab.ui.control.UIAxes
        bModeImage6                    matlab.ui.control.UIAxes
        ComparetoGoldStandardCheckBox  matlab.ui.control.CheckBox
        AverageCheckBox                matlab.ui.control.CheckBox
        distTrace6                     matlab.ui.control.UIAxes
        errorResults                   matlab.ui.control.Label
        channels                       matlab.ui.control.EditField
        ChannelsNMLabel                matlab.ui.control.Label
        SingleElementCheckBox          matlab.ui.control.CheckBox
        elementLocations               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = show_bMode(app, gs, cr)
            
            if ~app.SingleElementCheckBox.Value
                gs = gs.griddedElRaw;
                cr = cr.griddedElRaw;
                [img,~,ya] = griddedElementBModeImage(cr.RcvData,cr.Receive);
                gsImg = griddedElementBModeImage(gs.RcvData,gs.Receive);
                
                img(isnan(img)) = 0;
                gsImg(isnan(gsImg)) = 0;
                elements = transducerGeometry(0);
                
                % These images were produced with grids of elements so there won't be data
                % at the edges of the array. Find where we expect data.
                yFrames = unique(elements.ElementPos(:,2));
                yFrames = yFrames(2:end-1);
                
                
                for ii = 1:6
                    [~,yIdx] = min(abs(ya-yFrames(ii)));
                    curax = eval(['app.bModeImage',num2str(ii)]);
                    
                    if app.ComparetoGoldStandardCheckBox.Value
                        imshowpair(squeeze(img(:,yIdx,:)),squeeze(gsImg(:,yIdx,:)),'falsecolor','Parent',curax);
                    else
                        imshow(squeeze(log10(img(:,yIdx,:))),[],'parent',curax,'initialMagnification','fit');
                    end
                    axis('equal')
                    axis('tight')
                end
            else
                gs = gs.singleElRaw;
                cr = cr.singleElRaw;
                [img,~,ya] = singleElementBModeImage(cr.RcvData,cr.Receive);
                gsImg = singleElementBModeImage(gs.RcvData,gs.Receive);
                
                img(isnan(img)) = 0;
                gsImg(isnan(gsImg)) = 0;
                elements = transducerGeometry(0);
                
                % These images were produced with grids of elements so there won't be data
                % at the edges of the array. Find where we expect data.
                yFrames = unique(elements.ElementPos(:,2));
                yFrames = yFrames(2:end-1);
                
                
                for ii = 1:6
                    [~,yIdx] = min(abs(ya-yFrames(ii)));
                    curax = eval(['app.bModeImage',num2str(ii)]);
                    
                    if app.ComparetoGoldStandardCheckBox.Value
                        imshowpair(squeeze(img(:,yIdx,:)),squeeze(gsImg(:,yIdx,:)),'falsecolor','Parent',curax);
                    else
                        imshow(squeeze(log10(img(:,yIdx,:))),[],'parent',curax,'initialMagnification','fit');
                    end
                    axis('equal')
                    axis('tight')
                end
            end
            
        end
        
        function [gsRaw,crRaw,t,d] = getRawTraces(app,gs,cr)
            
            if ~app.SingleElementCheckBox.Value
                gs = gs.griddedElRaw;
                cr = cr.griddedElRaw;
                gridSize = 3;
            else
                gs = gs.singleElRaw;
                cr = cr.singleElRaw;
                gridSize = 1;
            end
            
            blocks = selectElementBlocks(gridSize);
            Trans = transducerGeometry(0);
            
            t = 1e6*(0:(cr.Receive(1).endSample-1))/(cr.Receive(1).ADCRate*1e6/cr.Receive(1).decimFactor);
            d = t*1.492/2+cr.Receive(1).startDepth*cr.Resource.Parameters.speedOfSound/(Trans.frequency*1e6)*1e3;
            
            for ii = 1:length(blocks)
                s = zeros(size(cr.RcvData(cr.Receive(ii).startSample:cr.Receive(ii).endSample,blocks{ii}(1))));
                for jj = 1:gridSize^2
                    curS = double(cr.RcvData(cr.Receive(ii).startSample:cr.Receive(ii).endSample,blocks{ii}(jj)));
                    s = curS+s;
                end
                crRaw(:,ii) = abs(hilbert(s));
                
                s = zeros(size(gs.RcvData(gs.Receive(ii).startSample:gs.Receive(ii).endSample,blocks{ii}(1))));
                for jj = 1:gridSize^2
                    curS = double(gs.RcvData(gs.Receive(ii).startSample:gs.Receive(ii).endSample,blocks{ii}(jj)));
                    s = curS+s;
                end
                gsRaw(:,ii) = abs(hilbert(s));
            end
        end
        
        function results = plotRawTraces(app,gsRaw,crRaw,d)
            cla(app.rawTraces)
            
            if ~app.SingleElementCheckBox.Value
                gridSize = 3;
            else
                gridSize = 1;
            end
            blocks = selectElementBlocks(gridSize);
            ch = str2num(app.channels.Value);
            if isempty(ch)
                msgbox('Invalid Channel Input. Must be N:M')
                ch = 1:180;
            elseif max(ch)>length(blocks)
                msgbox(['Invalid Channel Input. There are only ', num2str(length(blocks)), ' groups of elements.'])
                ch = ch(ch<181);
                if isempty(ch)
                    ch = 1:180;
                end
            end
            
            
            if ~app.AverageCheckBox.Value
                plot(app.rawTraces,d,gsRaw(:,ch),'-','linewidth',2,'Color',app.rawTraces.ColorOrder(2,:))
                hold(app.rawTraces,"on")
                plot(app.rawTraces,d,crRaw(:,ch),'-','linewidth',2,'Color',app.rawTraces.ColorOrder(1,:))
                app.rawTraces.XLabel.String = 'distance (mm)';
            else
                plot(app.rawTraces,d,mean(gsRaw(:,ch),2),'-','linewidth',2,'Color',app.rawTraces.ColorOrder(2,:));
                hold(app.rawTraces,"on")
                plot(app.rawTraces,d,mean(crRaw(:,ch),2),'-','linewidth',2,'Color',app.rawTraces.ColorOrder(1,:))
                app.rawTraces.XLabel.String = 'distance (mm)';
            end
            
            plotElementLocations(app,ch);
        end
        
        function plotDistance(app,gsRaw,crRaw,gsParams,d)
            if ~app.SingleElementCheckBox.Value
                gridSize = 3;
            else
                gridSize = 1;
            end
            blocks = selectElementBlocks(gridSize);
            Trans = transducerGeometry(0);
            axIdx = 1;
            if ~app.SingleElementCheckBox.Value
                elementsOfInterest = gsParams.elementsOfInterest;
            else
                elementsOfInterest = [74,79,124,125,194,199];
            end
            for ii = 1:length(blocks)
                centerElement = blocks{ii}(ceil(gridSize^2/2));
                if ismember(centerElement,elementsOfInterest)
                    curAx = eval(['app.distTrace',num2str(axIdx)]);
                    cla(curAx);
                    curCr = crRaw(:,ii);
                    curGs = gsRaw(:,ii);
                    
                    curCr(d>gsParams.powerRange(2)) = 0;
                    curCr(d<gsParams.powerRange(1)) = 0;
                    
                    curGs(d>gsParams.powerRange(2)) = 0;
                    curGs(d<gsParams.powerRange(1)) = 0;
                    
                    maxY = max([curGs;curCr]);
                    
                    plot(curAx,d,curGs/maxY,'-','LineWidth',2,'Color',curAx.ColorOrder(2,:))
                    hold(curAx,'on');
                    plot(curAx,d,curCr/maxY,'-','LineWidth',2,'Color',curAx.ColorOrder(1,:))
                    
                    % Determine distance error
                    idxGs = find(curGs/max(curGs)>0.5);
                    if isempty(idxGs)
                        msgbox('WARNING: No signal exceeded threshold!')
                        idxGs = 1;
                    else
                        idxGs = idxGs(1);
                    end
                    
                    idxCr = find(curCr/max(curCr)>0.5);
                    if isempty(idxCr)
                        msgbox('WARNING: No signal exceeded threshold!')
                        idxCr = 1;
                    else
                        idxCr = idxCr(1);
                    end
                    
                    plot(curAx,[d(idxGs),d(idxGs)],[0,1],'--','linewidth',2,'Color',curAx.ColorOrder(2,:))
                    plot(curAx,[d(idxCr),d(idxCr)],[0,1],'--','linewidth',2,'Color',curAx.ColorOrder(1,:))

                    distErr(axIdx) = abs(d(idxGs)-d(idxCr));
                    perDiffTmp = max(curCr)/max(curGs);
                    if perDiffTmp > 1
                        perDiff(axIdx) = perDiffTmp-1;
                    else
                        perDiff(axIdx) = 1-perDiffTmp;
                    end
                    title(curAx,['Block:',num2str(ii),', Distance Error = ',num2str(distErr(axIdx),2), 'mm; Pressure Error:', num2str(100*perDiff(axIdx),2), '%'])
                    
                    axis(curAx,[gsParams.powerRange',0,1])
                    if axIdx == 1
                        legend(curAx,'MR','Current')
                    end
                    axIdx = axIdx+1;
                end
            end
            app.errorResults.Text = ['Mean Error: ', num2str(mean(distErr),2), 'mm; Max Error: ', num2str(max(distErr),2),'mm',...
                '; Mean Pressure Difference', num2str(100*mean(perDiff),2), '; Max Pressure Difference', num2str(100*max(perDiff),2),'%'];
            
        end
        
        function results = plotElementLocations(app,ch)
            
            if ~app.SingleElementCheckBox.Value
                gridSize = 3;
            else
                gridSize = 1;
            end
            blocks = selectElementBlocks(gridSize);
            
            x = 1:32;
            y = 8:-1:1;
            [Y,X] = meshgrid(y,x);
            cla(app.elementLocations);
            plot(app.elementLocations,X(:),Y(:),'s','MarkerSize',20,'LineWidth',4)
            hold(app.elementLocations,"on");
            for ii = 1:length(ch)
                for jj = 1:length(blocks{ii})
                    app.elementLocations.ColorOrderIndex = 2;
                    if ~mod(blocks{ch(ii)}(jj),8)
                        xIdx = floor(blocks{ch(ii)}(jj)/8);
                    else
                        xIdx = floor(blocks{ch(ii)}(jj)/8)+1;
                    end
                    yIdx = mod(blocks{ch(ii)}(jj),8);
                    if yIdx==0
                        yIdx = 8;
                    end
                    plot(app.elementLocations,x(xIdx),y(yIdx),'s','MarkerSize',20,'LineWidth',4)
                end
            end
            axis(app.elementLocations,[0,33,-2,12]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgs)
            global gs cr gsParams expPath fName goldStd
            
            gs = imgs.gs;
            cr = imgs.cr;
            gsParams = imgs.gsParams;
            expPath = imgs.expPath;
            fName = imgs.fName;
            goldStd = imgs.goldStd;
            
            app.channels.Value = '1:180';
            
            show_bMode(app,gs,cr);
            
            [gsRaw,crRaw,~,d] = getRawTraces(app,gs,cr);
            plotRawTraces(app,gsRaw,crRaw,d)
            
            plotDistance(app,gsRaw,crRaw,gsParams,d);
        end

        % Value changed function: ComparetoGoldStandardCheckBox
        function ComparetoGoldStandardCheckBoxValueChanged(app, event)
            global gs cr
            show_bMode(app,gs,cr);
        end

        % Value changed function: AverageCheckBox
        function AverageCheckBoxValueChanged(app, event)
            global gs cr
            
            [gsRaw,crRaw,~,d] = getRawTraces(app,gs,cr);
            plotRawTraces(app,gsRaw,crRaw,d)
        end

        % Button pushed function: ReScanButton
        function ReScanButtonPushed(app, event)
            rescan = 1;
            save('guiOutput.mat','rescan')
            delete(app);
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            rescan = 0;
            save('guiOutput.mat','rescan')
            delete(app)
        end

        % Value changed function: channels
        function channelsValueChanged(app, event)
            global gs cr
            [gsRaw,crRaw,~,d] = getRawTraces(app,gs,cr);
            plotRawTraces(app,gsRaw,crRaw,d)
        end

        % Value changed function: SingleElementCheckBox
        function SingleElementCheckBoxValueChanged(app, event)
            global gs cr gsParams

            show_bMode(app,gs,cr);
            
            [gsRaw,crRaw,~,d] = getRawTraces(app,gs,cr);
            plotRawTraces(app,gsRaw,crRaw,d);
            plotDistance(app,gsRaw,crRaw,gsParams,d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.rawTraces = uiaxes(app.UIFigure);
            title(app.rawTraces, 'Raw Traces')
            xlabel(app.rawTraces, 'X')
            ylabel(app.rawTraces, 'Y')
            app.bModeImage1 = uiaxes(app.UIFigure);
            title(app.bModeImage1, '')
            xlabel(app.bModeImage1, '')
            ylabel(app.bModeImage1, '')
            app.bModeImage1.XTick = [];
            app.bModeImage1.XTickLabel = '';
            app.bModeImage1.YTick = [];
            app.bModeImage1.YTickLabel = '';
            app.distTrace1 = uiaxes(app.UIFigure);
            title(app.distTrace1, 'Distance to Skull')
            xlabel(app.distTrace1, '')
            ylabel(app.distTrace1, '')
            app.distTrace2 = uiaxes(app.UIFigure);
            title(app.distTrace2, '')
            xlabel(app.distTrace2, '')
            ylabel(app.distTrace2, '')
            app.distTrace3 = uiaxes(app.UIFigure);
            title(app.distTrace3, '')
            xlabel(app.distTrace3, '')
            ylabel(app.distTrace3, '')
            app.distTrace4 = uiaxes(app.UIFigure);
            title(app.distTrace4, '')
            xlabel(app.distTrace4, '')
            ylabel(app.distTrace4, '')
            app.distTrace5 = uiaxes(app.UIFigure);
            title(app.distTrace5, '')
            xlabel(app.distTrace5, '')
            ylabel(app.distTrace5, '')
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.ReScanButton = uibutton(app.UIFigure, 'push');
            app.ReScanButton.ButtonPushedFcn = createCallbackFcn(app, @ReScanButtonPushed, true);
            app.ReScanButton.Text = 'Re-Scan';
            app.goldStandardFile = uieditfield(app.UIFigure, 'text');
            app.SelectGoldStandardButton = uibutton(app.UIFigure, 'push');
            app.SelectGoldStandardButton.Text = 'Select Gold Standard';
            app.bModeImage2 = uiaxes(app.UIFigure);
            title(app.bModeImage2, '')
            xlabel(app.bModeImage2, '')
            ylabel(app.bModeImage2, '')
            app.bModeImage2.XTick = [];
            app.bModeImage2.XTickLabel = '';
            app.bModeImage2.YTick = [];
            app.bModeImage2.YTickLabel = '';
            app.bModeImage3 = uiaxes(app.UIFigure);
            title(app.bModeImage3, '')
            xlabel(app.bModeImage3, '')
            ylabel(app.bModeImage3, '')
            app.bModeImage3.XTick = [];
            app.bModeImage3.XTickLabel = '';
            app.bModeImage3.YTick = [];
            app.bModeImage3.YTickLabel = '';
            app.bModeImage4 = uiaxes(app.UIFigure);
            title(app.bModeImage4, '')
            xlabel(app.bModeImage4, '')
            ylabel(app.bModeImage4, '')
            app.bModeImage4.XTick = [];
            app.bModeImage4.XTickLabel = '';
            app.bModeImage4.YTick = [];
            app.bModeImage4.YTickLabel = '';
            app.bModeImage5 = uiaxes(app.UIFigure);
            title(app.bModeImage5, '')
            xlabel(app.bModeImage5, '')
            ylabel(app.bModeImage5, '')
            app.bModeImage5.XTick = [];
            app.bModeImage5.XTickLabel = '';
            app.bModeImage5.YTick = [];
            app.bModeImage5.YTickLabel = '';
            app.bModeImage6 = uiaxes(app.UIFigure);
            title(app.bModeImage6, '')
            xlabel(app.bModeImage6, '')
            ylabel(app.bModeImage6, '')
            app.bModeImage6.XTick = [];
            app.bModeImage6.XTickLabel = '';
            app.bModeImage6.YTick = [];
            app.bModeImage6.YTickLabel = '';
            app.ComparetoGoldStandardCheckBox = uicheckbox(app.UIFigure);
            app.ComparetoGoldStandardCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComparetoGoldStandardCheckBoxValueChanged, true);
            app.ComparetoGoldStandardCheckBox.Text = 'Compare to Gold Standard';
            app.AverageCheckBox = uicheckbox(app.UIFigure);
            app.AverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageCheckBoxValueChanged, true);
            app.AverageCheckBox.Text = 'Average';
            app.distTrace6 = uiaxes(app.UIFigure);
            title(app.distTrace6, '')
            xlabel(app.distTrace6, '')
            ylabel(app.distTrace6, '')
            app.errorResults = uilabel(app.UIFigure);
            app.errorResults.Text = '';
            app.channels = uieditfield(app.UIFigure, 'text');
            app.channels.ValueChangedFcn = createCallbackFcn(app, @channelsValueChanged, true);
            app.ChannelsNMLabel = uilabel(app.UIFigure);
            app.ChannelsNMLabel.Text = 'Channels (N:M)';
            app.SingleElementCheckBox = uicheckbox(app.UIFigure);
            app.SingleElementCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleElementCheckBoxValueChanged, true);
            app.SingleElementCheckBox.Text = 'Single Element';
            app.elementLocations = uiaxes(app.UIFigure);
            title(app.elementLocations, '')
            xlabel(app.elementLocations, '')
            ylabel(app.elementLocations, '')
            app.elementLocations.XTick = [];
            app.elementLocations.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifyPreTask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end