classdef prescribe_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        coronalMR                      matlab.ui.control.UIAxes
        sagittalMR                     matlab.ui.control.UIAxes
        MRImagesLabel                  matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        SelectFocalSpotButton          matlab.ui.control.Button
        coronalSim                     matlab.ui.control.UIAxes
        sagittalSim                    matlab.ui.control.UIAxes
        SelectImagesButton             matlab.ui.control.Button
        coronalSlider                  matlab.ui.control.Slider
        coronalLeft                    matlab.ui.control.Button
        coronalRight                   matlab.ui.control.Button
        sagSlider                      matlab.ui.control.Slider
        sagLeft                        matlab.ui.control.Button
        sagRight                       matlab.ui.control.Button
        SelectTxLocationButton         matlab.ui.control.Button
        lowWindowUp                    matlab.ui.control.Button
        lowWindowDown                  matlab.ui.control.Button
        WindowLabel                    matlab.ui.control.Label
        highWindowUp                   matlab.ui.control.Button
        highWindowDown                 matlab.ui.control.Button
        defaultWindow                  matlab.ui.control.Button
        SonicateButton                 matlab.ui.control.Button
        SelectTransducerDropDownLabel  matlab.ui.control.Label
        SelectTransducerDropDown       matlab.ui.control.DropDown
        FocusmmLabel                   matlab.ui.control.Label
        xEditFieldLabel                matlab.ui.control.Label
        yEditFieldLabel                matlab.ui.control.Label
        HydrophoneGridParameterswindowstepLabel  matlab.ui.control.Label
        xWindow                        matlab.ui.control.NumericEditField
        xStep                          matlab.ui.control.NumericEditField
        yWindow                        matlab.ui.control.NumericEditField
        yStep                          matlab.ui.control.NumericEditField
        zWindow                        matlab.ui.control.NumericEditField
        zStep                          matlab.ui.control.NumericEditField
        yEditFieldLabel_2              matlab.ui.control.Label
        pressureThUp                   matlab.ui.control.Button
        pressureThDown                 matlab.ui.control.Button
        pressureThLabel                matlab.ui.control.Label
        focalXBox                      matlab.ui.control.NumericEditField
        focalYBox                      matlab.ui.control.NumericEditField
        focalZBox                      matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        yLabel                         matlab.ui.control.Label
        zLabel                         matlab.ui.control.Label
        WaterTankCheckBox              matlab.ui.control.CheckBox
        axialMR                        matlab.ui.control.UIAxes
        axialSlider                    matlab.ui.control.Slider
        axialLeft                      matlab.ui.control.Button
        axialRight                     matlab.ui.control.Button
        axialSim                       matlab.ui.control.UIAxes
        ShowFocusButton                matlab.ui.control.Button
        ImageThermometerButton         matlab.ui.control.Button
        TestCouplingButton             matlab.ui.control.Button
        SavinginLabel                  matlab.ui.control.Label
        changeGoldStandard             matlab.ui.control.Button
        goldStandard                   matlab.ui.control.Label
        PulseDurationsecondsEditFieldLabel  matlab.ui.control.Label
        durationField                  matlab.ui.control.NumericEditField
        ElementVoltageVEditFieldLabel  matlab.ui.control.Label
        voltField                      matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        % Displays the coronal and sagittal images
        % @INPUTS
        %    app: pointer to parent GUI
        %    coronalImgVolume: 3D MR Data
        %    corWindow: window in which to display data
        %    corIdx: current coronal image index
        %    sagIdx: current sagittal image index
        %    axIdx: current axial image index
        % @Global INPUTS - These are set by the simulation so that they are only used if a simulation has been performed
        %    xAx: limits of image in x (P/A) dimension
        %    yAx: limits of image in y (R/L) dimension
        %    zAx: limits of image in z (S/I) dimension
        %    simulationCorIdx: Index of coronal images for which a simulation was performed
        
        function displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx)
            global p zAx simulationCorIdx pThreshold focus center transducerImg xAx yAx thermometerImage;
            
            % Plot Simulation
            if ~isnan(p)
                curCorImage = squeeze(coronalImgVolume(:,corIdx,:))';
                curSagImage = squeeze(coronalImgVolume(sagIdx,:,:))';
                curAxialImage = squeeze(coronalImgVolume(:,:,axIdx))';
                
                tmp = squeeze(abs(p(sagIdx,:,:)))';
                simImg = zeros(size(curSagImage));
                simImg(:,simulationCorIdx) = tmp;
                mrImg = squeeze(coronalImgVolume(sagIdx,:,:))';
                if max(simImg(:) > 0)
                    overlayImages(mrImg,simImg,app.sagittalSim,yAx,zAx,pThreshold*max(abs(p(:))),corWindow)
                else
                    cla(app.sagittalSim);
                end
                
                if ismember(corIdx,simulationCorIdx)
                    simImg = squeeze(abs(p(:,simulationCorIdx == corIdx,:)))';
                    
                    mrImg = squeeze(coronalImgVolume(:,corIdx,:))';
                    overlayImages(mrImg,simImg,app.coronalSim,xAx,zAx,pThreshold*max(abs(p(:))),corWindow)
                else
                    cla(app.coronalSim);
                end
                mrImg = squeeze(coronalImgVolume(:,:,axIdx))';
                simImg = zeros(size(mrImg));
                simImg(simulationCorIdx,:) = squeeze(abs(p(:,:,axIdx)))';
                overlayImages(mrImg,simImg,app.axialSim,xAx,yAx,pThreshold*max(abs(p(:))),corWindow)
            else
                cla(app.coronalSim);
                cla(app.sagittalSim);
            end
            
            % Plot focal location if available (overlay images is too slow)
            if length(focus) == 3
                if ~focus(1)
                    focus(1) = center(1);
                end
                if ~focus(2)
                    focus(2) = center(2);
                end
                
                x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1));
                y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
                z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3));
                
                [~,focusX] = min(abs(x-focus(1)));
                [~,focusY] = min(abs(y-focus(2)));
                [~,focusZ] = min(abs(z-focus(3)));
                wd = 10;

                coronalImgVolume((focusX-wd):(focusX+wd),(focusY-1):(focusY),focusZ) = max(coronalImgVolume(:));
                coronalImgVolume(focusX,(focusY-wd):(focusY+wd),focusZ) = max(coronalImgVolume(:));
                coronalImgVolume(focusX,focusY,(focusZ-floor(wd/2)):(focusZ+floor(wd/2))) = max(coronalImgVolume(:)); 
            end
            
            curCorImage = squeeze(coronalImgVolume(:,corIdx,:))';
            curSagImage = squeeze(coronalImgVolume(sagIdx,:,:))';
            curAxialImage = squeeze(coronalImgVolume(:,:,axIdx))';
            
            curCorImage(:,sagIdx) = 10*max(curCorImage(:));
            curCorImage(axIdx,:) = 10*max(curCorImage(:));
            
            curSagImage(:,corIdx) = 10*max(curSagImage(:));
            curSagImage(axIdx,:) = 10*max(curSagImage(:));
            
            curAxialImage(corIdx,:) = 10*max(curAxialImage(:));
            curAxialImage(:,sagIdx) = 10*max(curAxialImage(:));
            
            % Plot element locations if available
            if ~isempty(center)
                if ~isempty(thermometerImage)
                    overlay = transducerImg+thermometerImage/max(thermometerImage(:));
                else
                    overlay = transducerImg;
                end
            elseif ~isempty(thermometerImage)
                overlay = thermometerImage;
            else
                overlay = 0;
            end

            if max(overlay(:))
                overlayImages(curCorImage,squeeze(overlay(:,corIdx,:))',app.coronalMR,xAx,zAx,.1,corWindow,0.8,'winter')
                overlayImages(curSagImage,squeeze(overlay(sagIdx,:,:))',app.sagittalMR,yAx,zAx,.1,corWindow,0.8,'winter')
                overlayImages(curAxialImage,squeeze(overlay(:,:,axIdx))',app.axialMR,xAx,yAx,.1,corWindow,0.8,'winter')
            else
                imshow(curCorImage,corWindow,'Parent',app.coronalMR,'InitialMagnification','fit');
                imshow(curSagImage,corWindow,'Parent',app.sagittalMR,'xData',yAx,'yData',zAx);
                imshow(curAxialImage,corWindow,'Parent',app.axialMR,'xData',xAx,'yData',yAx);
            end
        end

        function setUpScope(app,f,nCycles)
            if ~exist('loadSoniqLibrary.m','file')
                addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\Aims\lib\soniq');
            end
            
            lib = loadSoniqLibrary();
            if ~isSoniqConnected(lib)
                openSoniq(lib);
            end
            
            tb = 1e6*4/f; % desired timebase in microseconds
            if nCycles > 10
                offset = 1e6*nCycles/(2*f); % distance tracking offset in microseconds
            else
                offset = -5;
            end
            setOscopeParameters(lib,{'distanceTracking',1,'distanceTrackingOffset',offset,'timeBase',tb,'nSamples',5e3});
            
            disp(['nCycles:', num2str(nCycles), ', offset', num2str(offset), ', tb:', num2str(tb)])
        end
        
        
        function results = saveData(app)
            global saveLoc simData usData thermometerImage mrData sonicationLog
            
            if isempty(saveLoc)
                msgbox('You must specify a location to save results')
                [file,path]=uiputfile('.mat','Select Location to Save Results');
                saveLoc = [path,file];
            end
            
            save(saveLoc, 'simData', 'usData', 'thermometerImage', 'mrData', 'sonicationLog')
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Currently defining the x axis as the slice dimension (from front to back of head), 
            % the z axis as pointing from top of head to bottom of head (matching the z-axis for 
            % the trnasducer), and the y-axis as pointing from one side of the head to the other.
            
            global coronalImgVolume xAx yAx zAx focus corWindow p frequency center pThreshold res simData usData thermometerImage mrData sonicationLog saveLoc;
            
            % Set the default threshold at which to display simulated/measured pressure
            pThreshold = 0.5;
            
            % p is the simulated pressure. Set to nan to show that pressure hasn't been simulated yet
            p = nan;
            
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\lib\gui_lib');
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\lib\transducerLocalization\');
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\lib\placementVerification\');
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\lib\griddedImage\');
            
            % Load a default image path
            imgPath = 'C:\Users\Verasonics\Box Sync\18623_08252016\004\';
%             imgPath = 'C:\Users\Taylor\Documents\Projects\txLocCovid\004\';
            
            % Set the drop down with possible transducers
            Txs = dir('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\*.m');
%             Txs = dir('C:\Users\Taylor\Documents\Projects\txLocCovid\verasonics\MonkeyTx\setupScripts\*.m');
            
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\')
            
            curIdx = 1;
            for ii = 1:length(Txs)
                if strcmp(Txs(ii).name,'setPaths.m')
                    continue
                else
                    txNames{curIdx} = Txs(ii).name;
                    curIdx = curIdx+1;
                end
            end
            app.SelectTransducerDropDown.Items = txNames;
            eval(['Trans = ' txNames{1}(1:end-2),';']);
            frequency = Trans.frequency*1e6;
            
            if ~exist('dicmMatFile.mat','file')
                [coronalImgVolume,header] = loadDicomDir(imgPath,app.UIFigure);
                save('dicmMatFile.mat','coronalImgVolume','header');
            else
                load('dicmMatFile.mat');
            end
            
            % Figure out orientation of image
            [xDim,yDim,zDim,xDir,yDir,zDir] =  assignTxAxes(header{1});
            coronalImgVolume = permute(coronalImgVolume,[xDim,yDim,zDim]);
            
            if xDir < 0
                coronalImgVolume = coronalImgVolume(end:-1:1,:,:);
            end
            if yDir < 0
                coronalImgVolume = coronalImgVolume(:,end:-1:1,:);
            end
            if zDir < 0
                coronalImgVolume = coronalImgVolume(:,:,end:-1:1);
            end
            
            % Axes
            xAx = [0,header{1}.PixelSpacing(1)*size(coronalImgVolume,1)];
            zAx = [0,header{1}.PixelSpacing(2)*size(coronalImgVolume,3)];
            yAx = [0,header{1}.SpacingBetweenSlices*size(coronalImgVolume,2)];
            
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1));
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3));
            
            res(1) = (x(2)-x(1));
            res(2) = (y(2)-y(1));
            res(3) = (z(2)-z(1));
            
            focus = [];
            center = [];
            
            % Display Images
            curCorImage = squeeze(coronalImgVolume(:,:,ceil(size(coronalImgVolume,zDim)/2)));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            thermometerImage = [];
            
            displayMrImages(app,coronalImgVolume,corWindow,ceil(size(coronalImgVolume,2)/2),ceil(size(coronalImgVolume,1)/2),ceil(size(coronalImgVolume,3)/2));
            
            % Set up proper limits on the sliders
            set(app.coronalSlider,'Limits',[1,size(coronalImgVolume,2)]);
            set(app.coronalSlider,'Value',ceil(size(coronalImgVolume,2)/2));
            
            set(app.sagSlider,'Limits',[1,size(coronalImgVolume,1)]);
            set(app.sagSlider,'Value',ceil(size(coronalImgVolume,1)/2));
            
            set(app.axialSlider,'Limits',[1,size(coronalImgVolume,3)]);
            set(app.axialSlider,'Value',ceil(size(coronalImgVolume,3)/2));
            
            %% Initialize variables and logs that will be saved throughout
            simData = [];
            usData = [];
            mrData = [];
            sonicationLog = [];
            saveLoc = [];
        end

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            % Allow the user to select a different set of coronal images
            global coronalImgVolume sagDim xAx yAx zAx p;
            
            % p is the simulated pressure. Loading a new data set invalidates it
            p = nan;
            
            imgPath = uigetdir();
            if ~imgPath % User cancelled
                return
            end
            addpath('gui_lib');
            % Currently defining the x axis as the slice dimension (from front to back of head), 
            % the z axis as pointing from top of head to bottom of head (matching the z-axis for 
            % the trnasducer), and the y-axis as pointing from one side of the head to the other.                        
            [coronalImgVolume,header] = loadDicomDir(imgPath);
            
            % Assuming this volume has coronal slices
            zDim = 3;
            sagDim = 2;
            
            % Axes
            xAx = [0,header{1}.PixelSpacing(1)*size(coronalImgVolume,1)];
            zAx = [0,header{1}.PixelSpacing(2)*size(coronalImgVolume,2)];
            yAx = [0,header{1}.SpacingBetweenSlices*size(coronalImgVolume,zDim)];
            
            % Display Images
            curCorImage = squeeze(coronalImgVolume(:,:,ceil(size(coronalImgVolume,zDim)/2)));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,ceil(size(coronalImgVolume,zDim)/2),ceil(size(coronalImgVolume,2)/2),ceil(size(coronalImgVolume,3)));
            
            % Set up proper limits on the sliders
            set(app.coronalSlider,'Limits',[1,size(coronalImgVolume,zDim)]);
            set(app.coronalSlider,'Value',ceil(size(coronalImgVolume,zDim)/2));
            
            set(app.sagSlider,'Limits',[1,size(coronalImgVolume,sagDim)]);
            set(app.sagSlider,'Value',ceil(size(coronalImgVolume,sagDim)/2));
            
        end

        % Value changed function: coronalSlider
        function coronalSliderValueChanged(app, event)
            global coronalImgVolume corWindow;
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            set(app.coronalSlider,'Value',round(corIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,round(corIdx),sagIdx,axIdx);
        end

        % Button pushed function: coronalLeft
        function coronalLeftButtonPushed(app, event)
            global coronalImgVolume corWindow;
            
            curSliderLoc = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            axIdx = get(app.axialSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                corIdx = curSliderLoc-1;
                set(app.coronalSlider,'Value',corIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Button pushed function: coronalRight
        function coronalRightButtonPushed(app, event)
            global coronalImgVolume corWindow;
            curSliderLoc = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            axIdx = get(app.axialSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,2)
                return
            else 
                corIdx = curSliderLoc+1;
                set(app.coronalSlider,'Value',corIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            global coronalImgVolume corWindow;
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            
            set(app.sagSlider,'Value',round(sagIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,round(sagIdx),axIdx);
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            global coronalImgVolume corWindow;
            
            curSliderLoc = get(app.sagSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            axIdx = get(app.axialSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                sagIdx = curSliderLoc-1;
                set(app.sagSlider,'Value',sagIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            global coronalImgVolume corWindow;
            
            curSliderLoc = get(app.sagSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            axIdx = get(app.axialSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,1)
                return
            else 
                sagIdx = curSliderLoc+1;
                set(app.sagSlider,'Value',sagIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Button pushed function: SelectTxLocationButton
        function SelectTxLocationButtonPushed(app, event)
            % Allows the user to select the location of a transducer. The user 
            % clicks on the current coronal slice and the transducer center is 
            % placed at the location selected by the user.
            global coronalImgVolume xAx yAx zAx center xTx yTx zTx corWindow focus res transducerImg mrData;
            volIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            h = figure;
            imshow(squeeze(coronalImgVolume(:,volIdx,:))',corWindow,'xData',xAx,'yData',zAx);
            title('Initial Guess')
            
            [center(1),center(3)] = ginput(1);
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1));
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3));
            center(2) = y(volIdx);
            close(h);
            
            [centerIdx,theta] = findTx(coronalImgVolume,1e-3*res,round(center./res));
            center(1) = x(centerIdx(1));
            center(2) = y(centerIdx(2));
            center(3) = z(centerIdx(3));
            transducerImg = drawTransducer(size(coronalImgVolume),centerIdx,theta,1e-3*res);
            
            focus = [];
            app.focalXBox.Value = 0;
            app.focalYBox.Value = 0;
            app.focalZBox.Value = 0;

            value = app.SelectTransducerDropDown.Value;
            eval(['Trans = ', value(1:end-2),';']);
            clear xTx yTx zTx;
            xTx = Trans.ElementPos(:,1);
            yTx = Trans.ElementPos(:,2);
            zTx = Trans.ElementPos(:,3);
            
            xTx = xTx+center(1);
            yTx = yTx+center(2);
            zTx = zTx+center(3);
            
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,sagIdx,axIdx);
            
            % Log result
            mrData.txLocation = center;
            mrData.txLocationDescription = 'Location of transducer origin relative to mrData.xAx, mrData.yAx, and mrData.zAx';
            mrData.imgData = coronalImgVolume;
            mrData.xAx = xAx;
            mrData.yAx = yAx;
            mrData.zAx = zAx;
            saveData(app);
        end

        % Button pushed function: SelectFocalSpotButton
        function SelectFocalSpotButtonPushed(app, event)
            % The user is able to click on the current coronal slice and select the desired focal location
            global coronalImgVolume xAx yAx zAx focus corWindow center elements xTx yTx zTx frequency;
            
            if isempty(center)
                msgbox('You must identify the transducer location first!')
                return
            end
            
            volIdx = app.coronalSlider.Value;
            h = figure;
            imshow(squeeze(coronalImgVolume(:,volIdx,:))',corWindow,'xData',xAx,'yData',zAx);
            
            [focalX,focalZ] = ginput(1);
            focalY = volIdx;
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
            
            focus = [focalX, y(round(focalY)), focalZ];
            app.focalXBox.Value = round(focus(1)-center(1));
            app.focalYBox.Value = round(focus(2)-center(2));
            app.focalZBox.Value = round(focus(3)-center(3));
            
            value = app.SelectTransducerDropDown.Value;
            eval(['Trans = ', value(1:end-2),';']);
            clear xTx yTx zTx;
            xTx = Trans.ElementPos(:,1);
            yTx = Trans.ElementPos(:,2);
            zTx = Trans.ElementPos(:,3);
            
            xTx = xTx+center(1);
            yTx = yTx+center(2);
            zTx = zTx+center(3);
            
            elements.x = xTx*1e-3;
            elements.y = yTx*1e-3;
            elements.z = zTx*1e-3;
            
            elements = steerArray(elements,focus*1e-3,frequency);
            elements.p = ones(size(xTx));
            
            close(h);
            
            corIdx = app.coronalSlider.Value;
            axIdx = app.axialSlider.Value;
            sagIdx = app.sagSlider.Value;
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Simulates the focal spot that would be made by the currently selected array
            % when focusing on the currently selected focal spot. Displays the results in
            % the bottom half of the GUI
            global coronalImgVolume xAx yAx zAx focus corWindow p simulationCorIdx elements frequency simData center;
            
            % Make sure necessary values are set
            if isempty(center)
                msgbox('You must identify the transducer location first!')
                return
            end
            if isempty(focus)
                msgbox('You must select a focus first!')
                return
            end
            
            value = app.SelectTransducerDropDown.Value;
            eval(['Trans = ', value(1:end-2),';']);
            clear xTx yTx zTx;
            xTx = Trans.ElementPos(:,1);
            yTx = Trans.ElementPos(:,2);
            zTx = Trans.ElementPos(:,3);
            
            xTx = xTx+center(1);
            yTx = yTx+center(2);
            zTx = zTx+center(3);
            
            volIdx = app.coronalSlider.Value;
            axIdx = app.axialSlider.Value;
            elements.x = xTx*1e-3;
            elements.y = yTx*1e-3;
            elements.z = zTx*1e-3;
            
            elements = steerArray(elements,focus*1e-3,frequency/1e6);
            elements.p = ones(size(xTx));
            
            % Simulate Coronal Plane
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2))*1e-3;
            simulationCorIdx = find(y>min(yTx*1e-3)&y<max(yTx*1e-3));
            
            % For 1D arrays there is only one y value so simulationCorIdx will be empty
            if isempty(simulationCorIdx)
                [~,simulationCorIdx] = min(abs(y-yTx(1)*1e-3));
            end
            % Make sure the focus is within the simulated field
            addSlices = 0;
            while focus(2)*1e-3 < min(y(simulationCorIdx)) | focus(2)*1e-3 > max(y(simulationCorIdx))
                simulationCorIdx = [min(simulationCorIdx)-1, simulationCorIdx, max(simulationCorIdx)+1];
                addSlices = 1;
            end
            % Add two more slices beyond the focus
            if addSlices
                simulationCorIdx = [min(simulationCorIdx)-1, simulationCorIdx, max(simulationCorIdx)+1];
                simulationCorIdx = [min(simulationCorIdx)-1, simulationCorIdx, max(simulationCorIdx)+1];
            end
            yGrid = y(simulationCorIdx);
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1))*1e-3;
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3))*1e-3;
            
            [Y,X,Z] = meshgrid(yGrid,x,z);
            grid.X = X;
            grid.Y = Y;
            grid.Z = Z;

            p = simulateArray(elements,frequency,grid,app.UIFigure);
            keyboard
            [~,focalSagIdx] = min(abs(x-focus(1)*1e-3));
            set(app.sagSlider,'Value',focalSagIdx);
            
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,axIdx);
            
            simData.corWindow = corWindow;
            simData.volIdx = volIdx;
            simData.focalSagIdx = focalSagIdx;
            simData.x = x;
            simData.y = y;
            simData.z = z;
            simData.p = p;
            simData.simulationCorIdx = simulationCorIdx;
            simData.focus = focus;
            simData.xTx = xTx;
            simData.yTx = yTx;
            simData.zTx = zTx;
            simData.description = ['Variables used for ray tracing simulation. corWindow, colIdx, focalSagIdx are indices into the MRData '...
                'for the focus. x, y, and z are the axes on which the simulation was performed. p is the resulting pressure at each x,y,z location '...
                'simulationCorIdx are the coronal slices on which the simulation was performed. Focus is the targeted focal spot (in x,y,z coordinates) '...
                'and xTx, yTz, and zTx are the locations of the elements along the x,y,z axes.'];
            
            saveData(app)

        end

        % Button pushed function: highWindowUp
        function highWindowUpButtonPushed(app, event)
            global coronalImgVolume corWindow
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            corWindow(2) = corWindow(2)/25+corWindow(2);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
        end

        % Button pushed function: highWindowDown
        function highWindowDownButtonPushed(app, event)
            global coronalImgVolume corWindow
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            corWindow(2) = -corWindow(2)/25+corWindow(2);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
        end

        % Button pushed function: lowWindowUp
        function lowWindowUpButtonPushed(app, event)
            global coronalImgVolume corWindow
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            corWindow(1) = corWindow(1)/25+corWindow(1);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
        end

        % Button pushed function: lowWindowDown
        function lowWindowDownButtonPushed(app, event)
            global coronalImgVolume corWindow
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            corWindow(1) = -corWindow(1)/25+corWindow(1);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
        end

        % Button pushed function: defaultWindow
        function defaultWindowButtonPushed(app, event)
            global coronalImgVolume corWindow
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            
            curCorImage = squeeze(coronalImgVolume(:,:,corIdx));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
        end

        % Button pushed function: SonicateButton
        function SonicateButtonPushed(app, event)
            % Uses VSX to do a scan of the transducer which is focused on the selected focal spot. 
            % The scan grid is defined by input boxes on the GUI. The results are displayed in the
            % lower half of the GUI once the scan is complete.
            global p focus xAx yAx zAx coronalImgVolume simulationCorIdx corWindow volIdx center elements savePath sonicationLog
            close all; % If a previous sonication still has a figure present in can cause an issue.
            clear data;
            % Make sure necessary values are set
            if isempty(center)
                msgbox('You must select the transducer location first!')
                return
            end
            if isempty(focus)
                msgbox('You must select a focus first!')
                return
            end
            
            % Get desired voltage and sonication duration
            voltage = app.voltField.Value;
            duration = app.durationField.Value;
            if isempty(voltage) || isempty(duration)
                msgbox('You must specify a voltage and duration for this sonication.')
                return
            end
            
            if app.WaterTankCheckBox.Value
                % Create the grid to be scanned based on the focal spot location
                yW = app.yWindow.Value;
                zW = app.zWindow.Value;
                yS = app.yStep.Value;
                zS = app.zStep.Value;
                xW = app.xWindow.Value;
                xS = app.xStep.Value;
    
                x = 1/10*round(10*((focus(1)-center(1)-xW/2):xS:(focus(1)-center(1)+xW/2)));
                if isempty(x)
                    x = 0;
                end
                y = 1/10*round(10*((focus(2)-center(2)-yW/2):yS:(focus(2)-center(2)+yW/2)));
                if isempty(y)
                    y = 0;
                end
                z = 1/10*round(10*((focus(3)-center(3)-zW/2):zS:(focus(3)-center(3)+zW/2)));
                if isempty(z)
                    z = 0;
                end
                
                [X,Z,Y] = meshgrid(x,z,y);
                save('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\gridInfo.mat','x','y','z','X','Y','Z');
                grid = load('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\gridInfo.mat');
                clear X Y Z x y z;
                
                value = app.SelectTransducerDropDown.Value;
                eval(['Trans = ', value(1:end-2),';']);
                clear xTx yTx zTx;
                xTx = Trans.ElementPos(:,1);
                yTx = Trans.ElementPos(:,2);
                zTx = Trans.ElementPos(:,3);
                frequency = Trans.frequency;
                duration = app.durationField.Value;
                nCycles = round(duration*frequency);
                
                xTx = xTx+center(1);
                yTx = yTx+center(2);
                zTx = zTx+center(3);
                
                volIdx = app.coronalSlider.Value;
                elements.x = xTx*1e-3;
                elements.y = yTx*1e-3;
                elements.z = zTx*1e-3;
                
                % Set up the oscilloscope
                setUpScope(app,frequency*1e6,nCycles);
                
                elements = steerArray(elements,focus*1e-3,frequency);
                delays = [elements.t]';
                
                eval([value(1:end-2),'(delays,''',savePath,'\'',''',app.SaveNameEditField.Value,''')']);
                
                evalin('base',['filename=''',value(1:end-2),'.mat''; VSX;'])
                if ~contains(value,'MR')
                    % Load in the measured data
                    pMeasured = zeros(size(grid.Z));
                    files = dir([savePath,'\',app.SaveNameEditField.Value,'*.snq']);
                    [~,idx] = sort([files.datenum]);
                    files = files(idx);
                    if length(files) ~= length(grid.Z(:))
                        error('Number of files and number of grid points do not match!')
                    end
                    for ii = 1:length(grid.Z(:))
                        [~,wv] = readWaveform([savePath,'\',files(ii).name]);
                        pMeasured(ii) = abs(min(wv));
                    end
                    
                    % Interpolate the data
                    gZ = (grid.Z+center(3))*1e-3;
                    gY = (grid.Y+center(2))*1e-3;
                    gX = (grid.X+center(1))*1e-3;
                    
                    y = linspace(yAx(1),yAx(2),size(coronalImgVolume,3))*1e-3;
                    dy = y(2)-y(1);
                    simulationCorIdx = find(y>min((grid.y+center(2))*1e-3)&y<max((grid.y+center(2))*1e-3));
                    % For 1D arrays there is only one y value so simulationCorIdx will be empty
                    if isempty(simulationCorIdx)
                        [~,simulationCorIdx] = min(abs(y-1e-3*(grid.y+center(2))));
                    end
                    
                    yGrid = y(simulationCorIdx);
                    x = linspace(xAx(1),xAx(2),size(coronalImgVolume,2))*1e-3;
                    z = linspace(zAx(1),zAx(2),size(coronalImgVolume,1))*1e-3;
                    
                    [X,Z,Y] = meshgrid(x,z,yGrid);
    
                    if ndims(gZ) == 3
                        p = interp3(gX,gZ,gY,pMeasured,X,Z,Y);
                    else
                        p = interp2(gX,gZ,pMeasured,X,Z);
                    end
                    p(isnan(p)) = 0;
                    [~,focalSagIdx] = min(abs(x-focus(2)*1e-3));
                    [~,focalAxIdx] = min(abs(z-focus(2)*1e-3));
                    set(app.sagSlider,'Value',focalSagIdx);
                    
                    volIdx = app.coronalSlider.Value;
                    
                    hydData.measuredPressure = pMeasured;
                    hydData.grid = grid;
                    
                    displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,focalAxIdx);
                end
            else
                duration = app.durationField.Value;
                voltage = app.voltField.Value;
                txFocus = (focus-center)*1e-3;
                value = app.SelectTransducerDropDown.Value;
                % Set up the mat file with the desired properties
                eval(['Trans = ', value(1:end-2), '(duration,voltage,txFocus);']);
                
                % Run VSX
                evalin('base',['filename=''',value(1:end-2),'.mat''; VSX;'])
                
                hydData = [];
            end
            
            if isempty(sonicationLog)
                sonicationLog = struct('elements', elements, 'voltage', voltage, 'duration', duration, 'focus', focus, 'center', center, 'hydData', hydData);
            else
                sonicationLog(end+1).elements = elements;
                sonicationLog(end+1).voltage = voltage;
                sonicationLog(end+1).duration = duration;
                sonicationLog(end+1).focus = focus;
                sonicationLog(end+1).center = center;
                sonicationLog(end+1).hydData = hydData;
            end
            saveData(app)
        end

        % Value changed function: SelectTransducerDropDown
        function SelectTransducerDropDownValueChanged(app, event)
            global frequency;
            
            value = app.SelectTransducerDropDown.Value;
            eval(['Trans = ', value(1:end-2),';']);
            frequency = Trans.frequency*1e6;
        end

        % Button pushed function: pressureThUp
        function pressureThUpButtonPushed(app, event)
            global pThreshold coronalImgVolume corWindow;
            if pThreshold <= 0.9+1e-12
                pThreshold = pThreshold + 0.1;
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                axIdx = app.axialSlider.Value;
                
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
                drawnow
                app.pressureThLabel.Text = ['Pressure Threshold: ', num2str(pThreshold)];
            end
        end

        % Button pushed function: pressureThDown
        function pressureThDownButtonPushed(app, event)
            global pThreshold coronalImgVolume corWindow;
            if pThreshold >= 0.1
                pThreshold = pThreshold - 0.1;
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                axIdx = app.axialSlider.Value;
                if pThreshold < 1e-12
                    pThreshold = 0;
                end
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
                drawnow
                app.pressureThLabel.Text = ['Pressure Threshold: ', num2str(pThreshold)];
            end
        end

        % Callback function
        function LoadButtonPushed(app, event)
            global coronalImgVolume savePath
            try
                load([savePath,'\',app.SaveNameEditField.Value,'stateInfo.mat']);
            catch
                msgbox(['Couldn''t find a state file in ', savePath])
            end
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,axIdx);
        end

        % Value changed function: focalXBox
        function focalXBoxValueChanged(app, event)
            global focus coronalImgVolume corWindow center
            
            value = app.focalXBox.Value;
            focus(1) = value+center(1);
            
            if length(focus) == 3 && focus(3) ~= 0
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                axIdx = app.axialSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Value changed function: focalYBox
        function focalYBoxValueChanged(app, event)
            global focus coronalImgVolume corWindow center
            
            value = app.focalYBox.Value;
            focus(2) = value+center(2);
            
            if length(focus) == 3 && focus(3) ~= 0
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                axIdx = app.axialSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Value changed function: focalZBox
        function focalZBoxValueChanged(app, event)
            global focus coronalImgVolume corWindow center
            
            value = app.focalZBox.Value;
            focus(3) = value+center(3);
            
            if length(focus) == 3 && focus(3) ~= 0
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                axIdx = app.axialSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Value changed function: WaterTankCheckBox
        function WaterTankCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: axialLeft
        function axialLeftButtonPushed(app, event)
            global coronalImgVolume corWindow;
            
            curSliderLoc = get(app.axialSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                axIdx = curSliderLoc-1;
                set(app.axialSlider,'Value',axIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Button pushed function: axialRight
        function axialRightButtonPushed(app, event)
            global coronalImgVolume corWindow;
            
            curSliderLoc = get(app.axialSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,3)
                return
            else 
                axIdx = curSliderLoc+1;
                set(app.axialSlider,'Value',axIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            end
        end

        % Value changed function: axialSlider
        function axialSliderValueChanged(app, event)
            global coronalImgVolume corWindow;
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            
            set(app.axialSlider,'Value',round(axIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,round(axIdx));
            
        end

        % Button pushed function: ShowFocusButton
        function ShowFocusButtonPushed(app, event)
            global focus xAx yAx zAx coronalImgVolume corWindow
            if isempty(focus)
                msgbox('You haven''t selected a focus yet.')
                return
            end
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1));
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3));
            
            [~,focusIdx(1)] = min(abs(x-focus(1)));
            [~,focusIdx(2)] = min(abs(y-focus(2)));
            [~,focusIdx(3)] = min(abs(z-focus(3)));
            
            set(app.sagSlider,'Value',focusIdx(1));
            set(app.coronalSlider,'Value',focusIdx(2));
            set(app.axialSlider,'Value',focusIdx(3));
            
            displayMrImages(app,coronalImgVolume,corWindow,focusIdx(2),focusIdx(1),focusIdx(3));
        end

        % Button pushed function: TestCouplingButton
        function TestCouplingButtonPushed(app, event)
            global saveLoc usData
            
            if isempty(saveLoc)
                uiwait(msgbox('You must specify a location to save results'));
                [file,path]=uiputfile('.mat','Select Location to Save Results');
                if file
                    saveLoc = [path,file];
                    app.SavinginLabel.Text = ['Saving in: ', saveLoc];
                else
                    return;
                end
            end
            
            goldStd = app.goldStandard.Text;
            svName = [saveLoc(1:end-4), '_couplingData.mat'];
            [posError,couplingErr,rawTraces] = testArrayPlacement(goldStd,svName);
            usData.posError = posError;
            usData.couplingErr = couplingErr;
            usData.rawTraces = rawTraces;
            usData.gsFile = goldStd;
            
            saveData(app);
        end

        % Button pushed function: changeGoldStandard
        function changeGoldStandardButtonPushed(app, event)
            [file,path]=uigetfile('.mat','Select Gold Standard File');
            if file
                app.goldStandard.Text = [path,file];
            end
        end

        % Button pushed function: ImageThermometerButton
        function ImageThermometerButtonPushed(app, event)
            global saveLoc thermometerImage xAx yAx zAx coronalImgVolume center corWindow usData
            
            if isempty(center)
                uiwait(msgbox('You must specify transducer location first!'))
                return;
            end
            
            if isempty(saveLoc)
                uiwait(msgbox('You must specify a location to save results'));
                [file,path]=uiputfile('.mat','Select Location to Save Results');
                if file
                    saveLoc = [path,file];
                    app.SavingInLabel.Text = ['Saving in: ', saveLoc];
                else
                    return;
                end
            end
            
            disp('Select the points (relative to the geometric focus) at which to place pixels.')
            x = input('  X Points >> ');
            y = input('  Y Points >> ');
            z = input('  Z Points >> ');
            
            if length(x)*length(y)*length(z) > 1450
                uiwait(msgbox('This image will take to long, select fewer voxels (max 1450)'))
                return
            end
            
            imageSaveName = [saveLoc(1:end-4),'_thermImage.mat'];
            focalImage_GUI;
            
            evalin('base','filename=''focalImage_GUI.mat''; VSX;')
            
            % Interpolate the data
            imgData = load(imageSaveName);
            gX = (imgData.x+center(1))*1e-3;
            gY = (imgData.y+center(2))*1e-3;
            gZ = (imgData.z+center(3))*1e-3;

            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1))*1e-3;
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2))*1e-3;
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3))*1e-3;
            
            [Y,X,Z] = meshgrid(y,x,z);
            
            thermometerImage = interp3(gY,gX,gZ,imgData.img,Y,X,Z,'linear',0);
            
            usData.thermometerData = imgData;
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            axIdx = app.axialSlider.Value;
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,axIdx);
            
            saveData(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.coronalMR = uiaxes(app.UIFigure);
            xlabel(app.coronalMR, 'X ->')
            ylabel(app.coronalMR, '<- Z')
            app.coronalMR.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.coronalMR.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagittalMR = uiaxes(app.UIFigure);
            xlabel(app.sagittalMR, 'Y ->')
            ylabel(app.sagittalMR, '<- Z')
            app.MRImagesLabel = uilabel(app.UIFigure);
            app.MRImagesLabel.Text = 'MR Images';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SelectFocalSpotButton = uibutton(app.UIFigure, 'push');
            app.SelectFocalSpotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFocalSpotButtonPushed, true);
            app.SelectFocalSpotButton.Text = 'Select Focal Spot';
            app.coronalSim = uiaxes(app.UIFigure);
            app.coronalSim.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagittalSim = uiaxes(app.UIFigure);
            app.sagittalSim.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SelectImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.coronalSlider = uislider(app.UIFigure);
            app.coronalSlider.MajorTicks = [];
            app.coronalSlider.ValueChangedFcn = createCallbackFcn(app, @coronalSliderValueChanged, true);
            app.coronalSlider.MinorTicks = [];
            app.coronalLeft = uibutton(app.UIFigure, 'push');
            app.coronalLeft.ButtonPushedFcn = createCallbackFcn(app, @coronalLeftButtonPushed, true);
            app.coronalLeft.Text = '<';
            app.coronalRight = uibutton(app.UIFigure, 'push');
            app.coronalRight.ButtonPushedFcn = createCallbackFcn(app, @coronalRightButtonPushed, true);
            app.coronalRight.Text = '>';
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.SelectTxLocationButton = uibutton(app.UIFigure, 'push');
            app.SelectTxLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTxLocationButtonPushed, true);
            app.SelectTxLocationButton.Text = 'Select Tx Location';
            app.lowWindowUp = uibutton(app.UIFigure, 'push');
            app.lowWindowUp.ButtonPushedFcn = createCallbackFcn(app, @lowWindowUpButtonPushed, true);
            app.lowWindowUp.Text = '^';
            app.lowWindowDown = uibutton(app.UIFigure, 'push');
            app.lowWindowDown.ButtonPushedFcn = createCallbackFcn(app, @lowWindowDownButtonPushed, true);
            app.lowWindowDown.Text = 'v';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.highWindowUp = uibutton(app.UIFigure, 'push');
            app.highWindowUp.ButtonPushedFcn = createCallbackFcn(app, @highWindowUpButtonPushed, true);
            app.highWindowUp.Text = '^';
            app.highWindowDown = uibutton(app.UIFigure, 'push');
            app.highWindowDown.ButtonPushedFcn = createCallbackFcn(app, @highWindowDownButtonPushed, true);
            app.highWindowDown.Text = 'v';
            app.defaultWindow = uibutton(app.UIFigure, 'push');
            app.defaultWindow.ButtonPushedFcn = createCallbackFcn(app, @defaultWindowButtonPushed, true);
            app.defaultWindow.Text = 'Default';
            app.SonicateButton = uibutton(app.UIFigure, 'push');
            app.SonicateButton.ButtonPushedFcn = createCallbackFcn(app, @SonicateButtonPushed, true);
            app.SonicateButton.Text = 'Sonicate';
            app.SelectTransducerDropDownLabel = uilabel(app.UIFigure);
            app.SelectTransducerDropDownLabel.Text = 'Select Transducer';
            app.SelectTransducerDropDown = uidropdown(app.UIFigure);
            app.SelectTransducerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTransducerDropDownValueChanged, true);
            app.FocusmmLabel = uilabel(app.UIFigure);
            app.FocusmmLabel.Text = 'Focus (mm): ';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.HydrophoneGridParameterswindowstepLabel = uilabel(app.UIFigure);
            app.HydrophoneGridParameterswindowstepLabel.Text = 'Hydrophone Grid Parameters (window, step)';
            app.xWindow = uieditfield(app.UIFigure, 'numeric');
            app.xWindow.Limits = [0 20];
            app.xWindow.Value = 5;
            app.xStep = uieditfield(app.UIFigure, 'numeric');
            app.xStep.Value = 0.5;
            app.yWindow = uieditfield(app.UIFigure, 'numeric');
            app.yStep = uieditfield(app.UIFigure, 'numeric');
            app.zWindow = uieditfield(app.UIFigure, 'numeric');
            app.zWindow.Value = 5;
            app.zStep = uieditfield(app.UIFigure, 'numeric');
            app.zStep.Value = 0.5;
            app.yEditFieldLabel_2 = uilabel(app.UIFigure);
            app.yEditFieldLabel_2.Text = 'z';
            app.pressureThUp = uibutton(app.UIFigure, 'push');
            app.pressureThUp.ButtonPushedFcn = createCallbackFcn(app, @pressureThUpButtonPushed, true);
            app.pressureThUp.Text = '^';
            app.pressureThDown = uibutton(app.UIFigure, 'push');
            app.pressureThDown.ButtonPushedFcn = createCallbackFcn(app, @pressureThDownButtonPushed, true);
            app.pressureThDown.Text = 'v';
            app.pressureThLabel = uilabel(app.UIFigure);
            app.pressureThLabel.Text = 'Pressure Threshold: 0.5';
            app.focalXBox = uieditfield(app.UIFigure, 'numeric');
            app.focalXBox.ValueChangedFcn = createCallbackFcn(app, @focalXBoxValueChanged, true);
            app.focalYBox = uieditfield(app.UIFigure, 'numeric');
            app.focalYBox.ValueChangedFcn = createCallbackFcn(app, @focalYBoxValueChanged, true);
            app.focalZBox = uieditfield(app.UIFigure, 'numeric');
            app.focalZBox.ValueChangedFcn = createCallbackFcn(app, @focalZBoxValueChanged, true);
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.WaterTankCheckBox = uicheckbox(app.UIFigure);
            app.WaterTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaterTankCheckBoxValueChanged, true);
            app.WaterTankCheckBox.Text = 'Water Tank';
            app.axialMR = uiaxes(app.UIFigure);
            app.axialSlider = uislider(app.UIFigure);
            app.axialSlider.MajorTicks = [];
            app.axialSlider.ValueChangedFcn = createCallbackFcn(app, @axialSliderValueChanged, true);
            app.axialSlider.MinorTicks = [];
            app.axialLeft = uibutton(app.UIFigure, 'push');
            app.axialLeft.ButtonPushedFcn = createCallbackFcn(app, @axialLeftButtonPushed, true);
            app.axialLeft.Text = '<';
            app.axialRight = uibutton(app.UIFigure, 'push');
            app.axialRight.ButtonPushedFcn = createCallbackFcn(app, @axialRightButtonPushed, true);
            app.axialRight.Text = '>';
            app.axialSim = uiaxes(app.UIFigure);
            app.axialSim.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ShowFocusButton = uibutton(app.UIFigure, 'push');
            app.ShowFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFocusButtonPushed, true);
            app.ShowFocusButton.Text = 'Show Focus';
            app.ImageThermometerButton = uibutton(app.UIFigure, 'push');
            app.ImageThermometerButton.ButtonPushedFcn = createCallbackFcn(app, @ImageThermometerButtonPushed, true);
            app.ImageThermometerButton.Text = 'Image Thermometer';
            app.TestCouplingButton = uibutton(app.UIFigure, 'push');
            app.TestCouplingButton.ButtonPushedFcn = createCallbackFcn(app, @TestCouplingButtonPushed, true);
            app.TestCouplingButton.Text = 'Test Coupling';
            app.SavinginLabel = uilabel(app.UIFigure);
            app.SavinginLabel.Text = 'Saving in: ';
            app.changeGoldStandard = uibutton(app.UIFigure, 'push');
            app.changeGoldStandard.ButtonPushedFcn = createCallbackFcn(app, @changeGoldStandardButtonPushed, true);
            app.changeGoldStandard.Text = 'Change Gold Standard';
            app.goldStandard = uilabel(app.UIFigure);
            app.goldStandard.Text = 'C:\Users\Verasonics\Desktop\Taylor\Data\Coupling\20200321\goldStandard_skullAlone.mat';
            app.PulseDurationsecondsEditFieldLabel = uilabel(app.UIFigure);
            app.PulseDurationsecondsEditFieldLabel.Text = 'Pulse Duration (seconds)';
            app.durationField = uieditfield(app.UIFigure, 'numeric');
            app.durationField.Limits = [0 30];
            app.ElementVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.ElementVoltageVEditFieldLabel.Text = 'Element Voltage (V)';
            app.voltField = uieditfield(app.UIFigure, 'numeric');
            app.voltField.Limits = [0 10];
        end
    end

    methods (Access = public)

        % Construct app
        function app = prescribe_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end