classdef prescribe_cmp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        coronalMR                      matlab.ui.control.UIAxes
        sagittalMR                     matlab.ui.control.UIAxes
        MRImagesLabel                  matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        SelectFocalSpotButton          matlab.ui.control.Button
        coronalSim                     matlab.ui.control.UIAxes
        sagittalSim                    matlab.ui.control.UIAxes
        SelectImagesButton             matlab.ui.control.Button
        coronalSlider                  matlab.ui.control.Slider
        coronalLeft                    matlab.ui.control.Button
        coronalRight                   matlab.ui.control.Button
        sagSlider                      matlab.ui.control.Slider
        sagLeft                        matlab.ui.control.Button
        sagRight                       matlab.ui.control.Button
        SelectTxLocationButton         matlab.ui.control.Button
        lowWindowUp                    matlab.ui.control.Button
        lowWindowDown                  matlab.ui.control.Button
        WindowLabel                    matlab.ui.control.Label
        highWindowUp                   matlab.ui.control.Button
        highWindowDown                 matlab.ui.control.Button
        defaultWindow                  matlab.ui.control.Button
        SaveNameEditFieldLabel         matlab.ui.control.Label
        SaveNameEditField              matlab.ui.control.EditField
        SonicateButton                 matlab.ui.control.Button
        SelectTransducerDropDownLabel  matlab.ui.control.Label
        SelectTransducerDropDown       matlab.ui.control.DropDown
        FocusLabel                     matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        SetPathButton                  matlab.ui.control.Button
        xEditFieldLabel                matlab.ui.control.Label
        yEditFieldLabel                matlab.ui.control.Label
        GridParameterswindowstepLabel  matlab.ui.control.Label
        xWindow                        matlab.ui.control.NumericEditField
        xStep                          matlab.ui.control.NumericEditField
        yWindow                        matlab.ui.control.NumericEditField
        yStep                          matlab.ui.control.NumericEditField
        zWindow                        matlab.ui.control.NumericEditField
        zStep                          matlab.ui.control.NumericEditField
        yEditFieldLabel_2              matlab.ui.control.Label
        pressureThUp                   matlab.ui.control.Button
        pressureThDown                 matlab.ui.control.Button
        pressureThLabel                matlab.ui.control.Label
        focalXBox                      matlab.ui.control.NumericEditField
        focalYBox                      matlab.ui.control.NumericEditField
        focalZBox                      matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        yLabel                         matlab.ui.control.Label
        zLabel                         matlab.ui.control.Label
        WaterTankCheckBox              matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        % Displays the coronal and sagittal images
        % @INPUTS
        %    app: pointer to parent GUI
        %    coronalImgVolume: 3D MR Data
        %    corWindow: window in which to display data
        %    corIdx: current coronal image index
        %    sagIdx: current sagittal image index
        %    xAx: limits of image in x (P/A) dimension
        %    yAx: limits of image in y (R/L) dimension
        % @Global INPUTS - These are set by the simulation so that they are only used if a simulation has been performed
        %    zAx: limits of image in z (S/I) dimension
        %    simulationCorIdx: Index of coronal images for which a simulation was performed
        
        function displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx)
            global p zAx simulationCorIdx pThreshold focus center;
            % Plot element locations if available (overlay images is too slow)
            if ~isempty(center)                
                x = linspace(xAx(1),xAx(2),size(coronalImgVolume,1));
                y = linspace(yAx(1),yAx(2),size(coronalImgVolume,2));
                z = linspace(zAx(1),zAx(2),size(coronalImgVolume,3));
                
                windowSize = 3;
                
                value = app.SelectTransducerDropDown.Value;
                eval(['[Trans,TW] = ', value(1:end-2)]);
                clear xTx yTx zTx;
                xTx = Trans.ElementPos(:,1);
                yTx = Trans.ElementPos(:,2);
                zTx = Trans.ElementPos(:,3);
                
                xTx = xTx+center(1);
                yTx = yTx+center(2);
                zTx = zTx+center(3);
                
                xIdx = zeros(size(xTx));
                yIdx = zeros(size(yTx));
                zIdx = zeros(size(zTx));
                for ii = 1:length(xTx)
                    [~,xIdx(ii)] = min(abs((xTx(ii))-x));
                    [~,yIdx(ii)] = min(abs((yTx(ii))-y));
                    [~,zIdx(ii)] = min(abs((zTx(ii))-z));
                    
                    zAll = (zIdx(ii)-windowSize):(zIdx(ii)+windowSize);
                    zAll(zAll < 1) = 1;
                    zAll(zAll > size(coronalImgVolume,1)) = size(coronalImgVolume,1);
                    
                    xAll = (xIdx(ii)-windowSize):(xIdx(ii)+windowSize);
                    xAll(xAll < 1) = 1;
                    xAll(xAll > size(coronalImgVolume,2)) = size(coronalImgVolume,2);
                    
                    yAll = (yIdx(ii)-windowSize):(yIdx(ii)+windowSize);
                    yAll(yAll < 1) = 1;
                    yAll(yAll > size(coronalImgVolume,3)) = size(coronalImgVolume,3);
                    
                    coronalImgVolume(xAll,yAll,zAll) = max(coronalImgVolume(:));
                end
            end
            
            % Plot focal location if available (overlay images is too slow)
            if length(focus) == 3
                if ~focus(1)
                    focus(1) = center(1);
                end
                if ~focus(2)
                    focus(2) = center(2);
                end
                
                x = linspace(xAx(1),xAx(2),size(coronalImgVolume,2));
                y = linspace(yAx(1),yAx(2),size(coronalImgVolume,3));
                z = linspace(zAx(1),zAx(2),size(coronalImgVolume,1));
                
                [~,focusX] = min(abs(x-focus(1)));
                [~,focusY] = min(abs(y-focus(2)));
                [~,focusZ] = min(abs(z-focus(3)));
                wd = 10;

                coronalImgVolume((focusX-wd):(focusX+wd),(focusY-1):(focusY),focusZ) = max(coronalImgVolume(:));
                coronalImgVolume(focusX,(focusY-wd):(focusY+wd),focusZ) = max(coronalImgVolume(:));
                coronalImgVolume(focusX,focusY,(focusZ-floor(wd/2)):(focusZ+floor(wd/2))) = max(coronalImgVolume(:)); 
            end
            
            curCorImage = squeeze(coronalImgVolume(:,corIdx,:))';
            curSagImage = squeeze(coronalImgVolume(sagIdx,:,:))';
            
            curCorImage(:,sagIdx) = 10*max(curCorImage(:));
            curSagImage(:,corIdx) = 10*max(curSagImage(:));
            
            imshow(curCorImage,corWindow,'Parent',app.coronalMR,'InitialMagnification','fit');
            imshow(curSagImage,corWindow,'Parent',app.sagittalMR,'xData',yAx,'yData',zAx);
            
            if ~isnan(p)
                tmp = squeeze(abs(p(sagIdx,:,:)));
                simImg = zeros(size(curSagImage));
                simImg(:,simulationCorIdx) = tmp;
                mrImg = squeeze(coronalImgVolume(sagIdx,:,:));
                if max(simImg(:) > 0)
                    overlayImages(mrImg,simImg,app.sagittalSim,zAx,yAx,pThreshold*max(abs(p(:))),corWindow)
                else
                    cla(app.sagittalSim);
                end
                
                if ismember(corIdx,simulationCorIdx)
                    simImg = squeeze(abs(p(:,simulationCorIdx == corIdx,:)));
                    
                    mrImg = squeeze(coronalImgVolume(:,corIdx,:));
                    overlayImages(mrImg,simImg,app.coronalSim,zAx,yAx,pThreshold*max(abs(p(:))),corWindow)
                else
                    cla(app.coronalSim);
                end
            else
                cla(app.coronalSim);
                cla(app.sagittalSim);
            end
        end

        function setUpScope(app,f,nCycles)
            if ~exist('loadSoniqLibrary.m','file')
                addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\Aims\lib\soniq');
            end
            
            lib = loadSoniqLibrary();
            if ~isSoniqConnected(lib)
                openSoniq(lib);
            end
            
            tb = 1e6*4/f; % desired timebase in microseconds
            if nCycles > 10
                offset = 1e6*nCycles/(2*f); % distance tracking offset in microseconds
            else
                offset = -5;
            end
            setOscopeParameters(lib,{'distanceTracking',1,'distanceTrackingOffset',offset,'timeBase',tb,'nSamples',5e3});
            
            disp(['nCycles:', num2str(nCycles), ', offset', num2str(offset), ', tb:', num2str(tb)])
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Currently defining the x axis as the slice dimension (from front to back of head), 
            % the z axis as pointing from top of head to bottom of head (matching the z-axis for 
            % the trnasducer), and the y-axis as pointing from one side of the head to the other.
            
            global coronalImgVolume sagDim xAx yAx zAx focus corWindow p xTx yTx zTx frequency center savePath pThreshold nCycles;
            
            % Set the default threshold at which to display simulated/measured pressure
            pThreshold = 0.5;
            
            savePath = 'C:\Users\Verasonics\Desktop\Taylor';
            app.SavePathEditField.Value = savePath;
            
            % p is the simulated pressure. Set to nan to show that pressure hasn't been simulated yet
            p = nan;
            
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\lib\gui_lib');
            
            % Load a default image path
%             imgPath = 'C:\Users\Verasonics\Box Sync\18623_08252016\004\';
            imgPath = 'C:\Users\Taylor\Documents\Projects\txLocCovid\004\';
            
            % Set the drop down with possible transducers
%             Txs = dir('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\*.m');
            Txs = dir('C:\Users\Taylor\Documents\Projects\txLocCovid\verasonics\MonkeyTx\setupScripts\*.m');
            
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\')
            
            curIdx = 1;
            for ii = 1:length(Txs)
                if strcmp(Txs(ii).name,'setPaths.m')
                    continue
                else
                    txNames{curIdx} = Txs(ii).name;
                    curIdx = curIdx+1;
                end
            end
            app.SelectTransducerDropDown.Items = txNames;
            eval(['[Trans,TW] = ' txNames{1}(1:end-2)]);
            frequency = Trans.frequency*1e6;
            nCycles = TW.Parameters(3)/2;
            
            if ~exist('dicmMatFile.mat','file')
                [coronalImgVolume,header] = loadDicomDir(imgPath,app.UIFigure);
                save('dicmMatFile.mat','coronalImgVolume','header');
            else
                load('dicmMatFile.mat');
            end
            
            % Figure out orientation of image
            [xDim,yDim,zDim,xDir,yDir,zDir] =  assignTxAxes(header{1});
            coronalImgVolume = permute(coronalImgVolume,[xDim,yDim,zDim]);
            
            if xDir < 0
                coronalImgVolume = coronalImgVolume(end:-1:1,:,:);
            end
            if yDir < 0
                coronalImgVolume = coronalImgVolume(:,end:-1:1,:);
            end
            if zDir < 0
                coronalImgVolume = coronalImgVolume(:,:,end:-1:1);
            end
            
            % Axes
            xAx = [0,header{1}.PixelSpacing(1)*size(coronalImgVolume,1)];
            zAx = [0,header{1}.PixelSpacing(2)*size(coronalImgVolume,3)];
            yAx = [0,header{1}.SpacingBetweenSlices*size(coronalImgVolume,2)];
            
            focus = [];
            center = [];
            
            % Display Images
            curCorImage = squeeze(coronalImgVolume(:,:,ceil(size(coronalImgVolume,zDim)/2)));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,ceil(size(coronalImgVolume,2)/2),ceil(size(coronalImgVolume,1)/2),xAx,yAx);
            
            % Set up proper limits on the sliders
            set(app.coronalSlider,'Limits',[1,size(coronalImgVolume,2)]);
            set(app.coronalSlider,'Value',ceil(size(coronalImgVolume,2)/2));
            
            set(app.sagSlider,'Limits',[1,size(coronalImgVolume,1)]);
            set(app.sagSlider,'Value',ceil(size(coronalImgVolume,1)/2));
        end

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            % Allow the user to select a different set of coronal images
            global coronalImgVolume sagDim xAx yAx zAx p;
            
            % p is the simulated pressure. Loading a new data set invalidates it
            p = nan;
            
            imgPath = uigetdir();
            if ~imgPath % User cancelled
                return
            end
            addpath('gui_lib');
            % Currently defining the x axis as the slice dimension (from front to back of head), 
            % the z axis as pointing from top of head to bottom of head (matching the z-axis for 
            % the trnasducer), and the y-axis as pointing from one side of the head to the other.                        
            [coronalImgVolume,header] = loadDicomDir(imgPath);
            
            % Assuming this volume has coronal slices
            zDim = 3;
            sagDim = 2;
            
            % Axes
            xAx = [0,header{1}.PixelSpacing(1)*size(coronalImgVolume,1)];
            zAx = [0,header{1}.PixelSpacing(2)*size(coronalImgVolume,2)];
            yAx = [0,header{1}.SpacingBetweenSlices*size(coronalImgVolume,zDim)];
            
            % Display Images
            curCorImage = squeeze(coronalImgVolume(:,:,ceil(size(coronalImgVolume,zDim)/2)));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,ceil(size(coronalImgVolume,zDim)/2),ceil(size(coronalImgVolume,2)/2),xAx,yAx);
            
            % Set up proper limits on the sliders
            set(app.coronalSlider,'Limits',[1,size(coronalImgVolume,zDim)]);
            set(app.coronalSlider,'Value',ceil(size(coronalImgVolume,zDim)/2));
            
            set(app.sagSlider,'Limits',[1,size(coronalImgVolume,sagDim)]);
            set(app.sagSlider,'Value',ceil(size(coronalImgVolume,sagDim)/2));
            
        end

        % Value changed function: coronalSlider
        function coronalSliderValueChanged(app, event)
            global coronalImgVolume corWindow xAx yAx;
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            set(app.coronalSlider,'Value',round(corIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,round(corIdx),sagIdx,xAx,yAx);
        end

        % Button pushed function: coronalLeft
        function coronalLeftButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx;
            
            curSliderLoc = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                corIdx = curSliderLoc-1;
                set(app.coronalSlider,'Value',corIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Button pushed function: coronalRight
        function coronalRightButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx;
            curSliderLoc = get(app.coronalSlider,'Value');
            sagIdx = get(app.sagSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,3)
                return
            else 
                corIdx = curSliderLoc+1;
                set(app.coronalSlider,'Value',corIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            global coronalImgVolume xAx yAx corWindow;
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            
            set(app.sagSlider,'Value',round(sagIdx));
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,round(sagIdx),xAx,yAx);
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            global coronalImgVolume xAx yAx corWindow;
            
            curSliderLoc = get(app.sagSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            if curSliderLoc == 1
                return
            else 
                sagIdx = curSliderLoc-1;
                set(app.sagSlider,'Value',sagIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            global coronalImgVolume sagDim xAx yAx corWindow;
            
            curSliderLoc = get(app.sagSlider,'Value');
            corIdx = get(app.coronalSlider,'Value');
            if curSliderLoc == size(coronalImgVolume,sagDim)
                return
            else 
                sagIdx = curSliderLoc+1;
                set(app.sagSlider,'Value',sagIdx);
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Button pushed function: SelectTxLocationButton
        function SelectTxLocationButtonPushed(app, event)
            % Allows the user to select the location of a transducer. The user 
            % clicks on the current coronal slice and the transducer center is 
            % placed at the location selected by the user.
            global coronalImgVolume xAx yAx zAx center xTx yTx zTx corWindow focus;
            volIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            h = figure;
            imshow(squeeze(coronalImgVolume(:,volIdx,:))',corWindow,'xData',zAx,'yData',xAx);
            
            [center(1),center(3)] = ginput(1);
            
            close(h);
            
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,3));
            
            center(2) = y(volIdx);
            focus = [];
            app.focalXBox.Value = 0;
            app.focalYBox.Value = 0;
            app.focalZBox.Value = 0;

            value = app.SelectTransducerDropDown.Value;
            eval(['[Trans,TW] = ', value(1:end-2)]);
            clear xTx yTx zTx;
            xTx = Trans.ElementPos(:,1);
            yTx = Trans.ElementPos(:,2);
            zTx = Trans.ElementPos(:,3);
            
            xTx = xTx+center(1);
            yTx = yTx+center(2);
            zTx = zTx+center(3);
            
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: SelectFocalSpotButton
        function SelectFocalSpotButtonPushed(app, event)
            % The user is able to click on the current coronal slice and select the desired focal location
            global coronalImgVolume xAx yAx zAx focus corWindow center elements xTx yTx zTx frequency;
            volIdx = app.coronalSlider.Value;
            h = figure;
            imshow(coronalImgVolume(:,:,volIdx),corWindow,'xData',xAx,'yData',zAx);
            
            [focalX,focalZ] = ginput(1);
            focalY = volIdx;
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,3));
            
            focus = [focalX, y(round(focalY)), focalZ];
            app.focalXBox.Value = round(focus(1)-center(1));
            app.focalYBox.Value = round(focus(2)-center(2));
            app.focalZBox.Value = round(focus(3)-center(3));
            
            elements.x = xTx*1e-3;
            elements.y = yTx*1e-3;
            elements.z = zTx*1e-3;
            
            elements = steerArray(elements,focus*1e-3,frequency);
            elements.p = ones(size(xTx));
            
            close(h);
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Simulates the focal spot that would be made by the currently selected array
            % when focusing on the currently selected focal spot. Displays the results in
            % the bottom half of the GUI
            global coronalImgVolume xAx yAx zAx focus corWindow p simulationCorIdx elements frequency savePath center;
            
            % Make sure necessary values are set
            if isempty(center)
                msgbox('You must select the transducer location first!')
                return
            end
            if isempty(focus)
                msgbox('You must select a focus first!')
                return
            end
            
            if strcmp(app.SaveNameEditField.Value,'File Name')
                msgbox('You must supply a file name to save the results!')
                return
            elseif exist([savePath, '\', app.SaveNameEditField.Value],'file') || exist([app.SaveNameEditField.Value,'.mat'],'file')
                overwrite = questdlg([app.SaveNameEditField.Value, ' already exists. Overwrite?'],'Overwrite?','Yes','No','No');
                if strcmp(overwrite, 'No')
                    msgbox('Please supply a different file name')
                    return
                end
            end
            
            value = app.SelectTransducerDropDown.Value;
            eval(['[Trans,TW] = ', value(1:end-2)]);
            clear xTx yTx zTx;
            xTx = Trans.ElementPos(:,1);
            yTx = Trans.ElementPos(:,2);
            zTx = Trans.ElementPos(:,3);
            
            xTx = xTx+center(1);
            yTx = yTx+center(2);
            zTx = zTx+center(3);
            
            volIdx = app.coronalSlider.Value;
            elements.x = xTx*1e-3;
            elements.y = yTx*1e-3;
            elements.z = zTx*1e-3;
            
            elements = steerArray(elements,focus*1e-3,frequency);
            elements.p = ones(size(xTx));
            
            % Simulate Coronal Plane
            y = linspace(yAx(1),yAx(2),size(coronalImgVolume,3))*1e-3;
            simulationCorIdx = find(y>min(yTx*1e-3)&y<max(yTx*1e-3));
            % For 1D arrays there is only one y value so simulationCorIdx will be empty
            if isempty(simulationCorIdx)
                [~,simulationCorIdx] = min(abs(y-yTx(1)*1e-3));
            end
            keyboard
            yGrid = y(simulationCorIdx);
            x = linspace(xAx(1),xAx(2),size(coronalImgVolume,2))*1e-3;
            z = linspace(zAx(1),zAx(2),size(coronalImgVolume,1))*1e-3;
            
            [X,Z,Y] = meshgrid(x,z,yGrid);
            grid.X = X;
            grid.Y = Y;
            grid.Z = Z;

            p = simulateArray(elements,frequency,grid,app.UIFigure);
            
            [~,focalSagIdx] = min(abs(x-focus(1)*1e-3));
            set(app.sagSlider,'Value',focalSagIdx);
            
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,xAx,yAx);
            
            save([savePath, '\', app.SaveNameEditField.Value],...
                'coronalImgVolume','corWindow','volIdx','focalSagIdx','xAx','yAx','p','simulationCorIdx','xAx','yAx','zAx','focus','xTx','yTx','zTx');

        end

        % Button pushed function: highWindowUp
        function highWindowUpButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(2) = corWindow(2)/25+corWindow(2);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: highWindowDown
        function highWindowDownButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(2) = -corWindow(2)/25+corWindow(2);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: lowWindowUp
        function lowWindowUpButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(1) = corWindow(1)/25+corWindow(1);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: lowWindowDown
        function lowWindowDownButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            corWindow(1) = -corWindow(1)/25+corWindow(1);
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Button pushed function: defaultWindow
        function defaultWindowButtonPushed(app, event)
            global coronalImgVolume corWindow xAx yAx
            
            corIdx = app.coronalSlider.Value;
            sagIdx = app.sagSlider.Value;
            
            curCorImage = squeeze(coronalImgVolume(:,:,corIdx));
            corWindow = [min(curCorImage(:)),max(curCorImage(:))];
            
            displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
        end

        % Value changed function: SaveNameEditField
        function SaveNameEditFieldValueChanged(app, event)
            global saveFileName;
            saveFileName = app.SaveNameEditField.Value;
        end

        % Button pushed function: SonicateButton
        function SonicateButtonPushed(app, event)
            % Uses VSX to do a scan of the transducer which is focused on the selected focal spot. 
            % The scan grid is defined by input boxes on the GUI. The results are displayed in the
            % lower half of the GUI once the scan is complete.
            global p focus xAx yAx zAx coronalImgVolume simulationCorIdx corWindow volIdx center elements savePath
            close all; % If a previous sonication still has a figure present in can cause an issue.
            value = app.SelectTransducerDropDown.Value;
            clear data;
            % Make sure necessary values are set
            if isempty(center)
                msgbox('You must select the transducer location first!')
                return
            end
            if isempty(focus)
                msgbox('You must select a focus first!')
                return
            end
            
            if strcmp(app.SaveNameEditField.Value,'File Name')
                msgbox('You must supply a file name to save the results!')
                return
            end
            
            files = dir([savePath,'\',app.SaveNameEditField.Value,'*.snq']);
            if ~isempty(files)
                ow = questdlg('A sonication with this name already exists in this directory, overwrite?','Directory Exists');
                if strcmp(ow,'Yes')
                    for ii = 1 : length(files)
                        delete([savePath, '\', files(ii).name]);
                    end
                else
                    msgbox('Please select different path')
                    return
                end
            end
            
            % Create the grid to be scanned based on the focal spot location
            yW = app.yWindow.Value;
            zW = app.zWindow.Value;
            yS = app.yStep.Value;
            zS = app.zStep.Value;
            xW = app.xWindow.Value;
            xS = app.xStep.Value;

            x = 1/10*round(10*((focus(1)-center(1)-xW/2):xS:(focus(1)-center(1)+xW/2)));
            if isempty(x)
                x = 0;
            end
            y = 1/10*round(10*((focus(2)-center(2)-yW/2):yS:(focus(2)-center(2)+yW/2)));
            if isempty(y)
                y = 0;
            end
            z = 1/10*round(10*((focus(3)-center(3)-zW/2):zS:(focus(3)-center(3)+zW/2)));
            if isempty(z)
                z = 0;
            end
            
            [X,Z,Y] = meshgrid(x,z,y);
            save('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\gridInfo.mat','x','y','z','X','Y','Z');
            grid = load('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\MonkeyTx\setupScripts\gridInfo.mat');
            clear X Y Z x y z;
            
            value = app.SelectTransducerDropDown.Value;
            eval(['[Trans,TW] = ', value(1:end-2)]);
            clear xTx yTx zTx;
            xTx = Trans.ElementPos(:,1);
            yTx = Trans.ElementPos(:,2);
            zTx = Trans.ElementPos(:,3);
            frequency = Trans.frequency;
            nCycles = TW.Parameters(3)/2;
            
            xTx = xTx+center(1);
            yTx = yTx+center(2);
            zTx = zTx+center(3);
            
            volIdx = app.coronalSlider.Value;
            elements.x = xTx*1e-3;
            elements.y = yTx*1e-3;
            elements.z = zTx*1e-3;
            
            % Set up the oscilloscope
            setUpScope(app,frequency*1e6,nCycles);
            
            elements = steerArray(elements,focus*1e-3,frequency);
            delays = [elements.t]';
            
            eval([value(1:end-2),'(delays,''',savePath,'\'',''',app.SaveNameEditField.Value,''')']);
            
            evalin('base',['filename=''',value(1:end-2),'.mat''; VSX;'])
            if ~contains(value,'MR')
                % Load in the measured data
                pMeasured = zeros(size(grid.Z));
                files = dir([savePath,'\',app.SaveNameEditField.Value,'*.snq']);
                [~,idx] = sort([files.datenum]);
                files = files(idx);
                if length(files) ~= length(grid.Z(:))
                    error('Number of files and number of grid points do not match!')
                end
                for ii = 1:length(grid.Z(:))
                    [~,wv] = readWaveform([savePath,'\',files(ii).name]);
                    pMeasured(ii) = abs(min(wv));
                end
                
                % Interpolate the data
                gZ = (grid.Z+center(3))*1e-3;
                gY = (grid.Y+center(2))*1e-3;
                gX = (grid.X+center(1))*1e-3;
                
                y = linspace(yAx(1),yAx(2),size(coronalImgVolume,3))*1e-3;
                dy = y(2)-y(1);
                simulationCorIdx = find(y>min((grid.y+center(2))*1e-3)&y<max((grid.y+center(2))*1e-3));
                % For 1D arrays there is only one y value so simulationCorIdx will be empty
                if isempty(simulationCorIdx)
                    [~,simulationCorIdx] = min(abs(y-1e-3*(grid.y+center(2))));
                end
                
                yGrid = y(simulationCorIdx);
                x = linspace(xAx(1),xAx(2),size(coronalImgVolume,2))*1e-3;
                z = linspace(zAx(1),zAx(2),size(coronalImgVolume,1))*1e-3;
                
                [X,Z,Y] = meshgrid(x,z,yGrid);

                if ndims(gZ) == 3
                    p = interp3(gX,gZ,gY,pMeasured,X,Z,Y);
                else
                    p = interp2(gX,gZ,pMeasured,X,Z);
                end
                p(isnan(p)) = 0;
                [~,focalSagIdx] = min(abs(x-focus(2)*1e-3));
                set(app.sagSlider,'Value',focalSagIdx);
                
                volIdx = app.coronalSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,xAx,yAx);
            end
%             save([savePath,'\',app.SaveNameEditField.Value,'stateInfo.mat'],'grid','corWindow','volIdx','focalSagIdx','p');
        end

        % Value changed function: SelectTransducerDropDown
        function SelectTransducerDropDownValueChanged(app, event)
            global frequency nCycles;
            
            value = app.SelectTransducerDropDown.Value;
            eval(['[Trans,TW] = ', value(1:end-2)]);
            frequency = Trans.frequency*1e6;
            nCycles = TW.Parameters(3)/2;
           
        end

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
            global savePath
            selPath = uigetdir(savePath);
            if selPath ~= 0
                app.SavePathEditField.Value = selPath;
                savePath = selPath;
            end
        end

        % Button pushed function: pressureThUp
        function pressureThUpButtonPushed(app, event)
            global pThreshold coronalImgVolume corWindow xAx yAx;
            if pThreshold <= 0.9+1e-12
                pThreshold = pThreshold + 0.1;
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
                drawnow
                app.pressureThLabel.Text = ['Pressure Threshold: ', num2str(pThreshold)];
            end
        end

        % Button pushed function: pressureThDown
        function pressureThDownButtonPushed(app, event)
            global pThreshold coronalImgVolume corWindow xAx yAx;
            if pThreshold >= 0.1
                pThreshold = pThreshold - 0.1;
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                if pThreshold < 1e-12
                    pThreshold = 0;
                end
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
                drawnow
                app.pressureThLabel.Text = ['Pressure Threshold: ', num2str(pThreshold)];
            end
        end

        % Callback function
        function LoadButtonPushed(app, event)
            global coronalImgVolume xAx yAx savePath
            try
                load([savePath,'\',app.SaveNameEditField.Value,'stateInfo.mat']);
            catch
                msgbox(['Couldn''t find a state file in ', savePath])
            end
            displayMrImages(app,coronalImgVolume,corWindow,volIdx,focalSagIdx,xAx,yAx);
        end

        % Value changed function: focalXBox
        function focalXBoxValueChanged(app, event)
            global focus coronalImgVolume corWindow xAx yAx center
            
            value = app.focalXBox.Value;
            focus(1) = value+center(1);
            
            if length(focus) == 3 && focus(3) ~= 0
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Value changed function: focalYBox
        function focalYBoxValueChanged(app, event)
            global focus coronalImgVolume corWindow xAx yAx center
            
            value = app.focalYBox.Value;
            focus(2) = value+center(2);
            
            if length(focus) == 3 && focus(3) ~= 0
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Value changed function: focalZBox
        function focalZBoxValueChanged(app, event)
            global focus coronalImgVolume corWindow xAx yAx center
            
            value = app.focalZBox.Value;
            focus(3) = value+center(3);
            
            if length(focus) == 3 && focus(3) ~= 0
                corIdx = app.coronalSlider.Value;
                sagIdx = app.sagSlider.Value;
                displayMrImages(app,coronalImgVolume,corWindow,corIdx,sagIdx,xAx,yAx);
            end
        end

        % Value changed function: WaterTankCheckBox
        function WaterTankCheckBoxValueChanged(app, event)
            value = app.WaterTankCheckBox.Value;
            if value
                 % Set up the oscilloscope
                setUpScope(app,frequency,nCycles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.coronalMR = uiaxes(app.UIFigure);
            title(app.coronalMR, 'Coronal')
            xlabel(app.coronalMR, 'X ->')
            ylabel(app.coronalMR, '<- Z')
            app.coronalMR.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.coronalMR.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagittalMR = uiaxes(app.UIFigure);
            title(app.sagittalMR, 'Sagittal')
            xlabel(app.sagittalMR, 'Y ->')
            ylabel(app.sagittalMR, '<- Z')
            app.MRImagesLabel = uilabel(app.UIFigure);
            app.MRImagesLabel.Text = 'MR Images';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SelectFocalSpotButton = uibutton(app.UIFigure, 'push');
            app.SelectFocalSpotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFocalSpotButtonPushed, true);
            app.SelectFocalSpotButton.Text = 'Select Focal Spot';
            app.coronalSim = uiaxes(app.UIFigure);
            title(app.coronalSim, 'Coronal')
            xlabel(app.coronalSim, 'mm')
            ylabel(app.coronalSim, 'mm')
            app.coronalSim.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.sagittalSim = uiaxes(app.UIFigure);
            title(app.sagittalSim, 'Sagittal')
            xlabel(app.sagittalSim, 'mm')
            ylabel(app.sagittalSim, 'mm')
            app.sagittalSim.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SelectImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.coronalSlider = uislider(app.UIFigure);
            app.coronalSlider.MajorTicks = [];
            app.coronalSlider.ValueChangedFcn = createCallbackFcn(app, @coronalSliderValueChanged, true);
            app.coronalSlider.MinorTicks = [];
            app.coronalLeft = uibutton(app.UIFigure, 'push');
            app.coronalLeft.ButtonPushedFcn = createCallbackFcn(app, @coronalLeftButtonPushed, true);
            app.coronalLeft.Text = '<';
            app.coronalRight = uibutton(app.UIFigure, 'push');
            app.coronalRight.ButtonPushedFcn = createCallbackFcn(app, @coronalRightButtonPushed, true);
            app.coronalRight.Text = '>';
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.SelectTxLocationButton = uibutton(app.UIFigure, 'push');
            app.SelectTxLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTxLocationButtonPushed, true);
            app.SelectTxLocationButton.Text = 'Select Tx Location';
            app.lowWindowUp = uibutton(app.UIFigure, 'push');
            app.lowWindowUp.ButtonPushedFcn = createCallbackFcn(app, @lowWindowUpButtonPushed, true);
            app.lowWindowUp.Text = '^';
            app.lowWindowDown = uibutton(app.UIFigure, 'push');
            app.lowWindowDown.ButtonPushedFcn = createCallbackFcn(app, @lowWindowDownButtonPushed, true);
            app.lowWindowDown.Text = 'v';
            app.WindowLabel = uilabel(app.UIFigure);
            app.WindowLabel.Text = 'Window';
            app.highWindowUp = uibutton(app.UIFigure, 'push');
            app.highWindowUp.ButtonPushedFcn = createCallbackFcn(app, @highWindowUpButtonPushed, true);
            app.highWindowUp.Text = '^';
            app.highWindowDown = uibutton(app.UIFigure, 'push');
            app.highWindowDown.ButtonPushedFcn = createCallbackFcn(app, @highWindowDownButtonPushed, true);
            app.highWindowDown.Text = 'v';
            app.defaultWindow = uibutton(app.UIFigure, 'push');
            app.defaultWindow.ButtonPushedFcn = createCallbackFcn(app, @defaultWindowButtonPushed, true);
            app.defaultWindow.Text = 'Default';
            app.SaveNameEditFieldLabel = uilabel(app.UIFigure);
            app.SaveNameEditFieldLabel.Text = 'Save Name';
            app.SaveNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveNameEditField.ValueChangedFcn = createCallbackFcn(app, @SaveNameEditFieldValueChanged, true);
            app.SaveNameEditField.Value = 'File Name';
            app.SonicateButton = uibutton(app.UIFigure, 'push');
            app.SonicateButton.ButtonPushedFcn = createCallbackFcn(app, @SonicateButtonPushed, true);
            app.SonicateButton.Text = 'Sonicate';
            app.SelectTransducerDropDownLabel = uilabel(app.UIFigure);
            app.SelectTransducerDropDownLabel.Text = 'Select Transducer';
            app.SelectTransducerDropDown = uidropdown(app.UIFigure);
            app.SelectTransducerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTransducerDropDownValueChanged, true);
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus: ';
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.SetPathButton = uibutton(app.UIFigure, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.GridParameterswindowstepLabel = uilabel(app.UIFigure);
            app.GridParameterswindowstepLabel.Text = 'Grid Parameters (window, step)';
            app.xWindow = uieditfield(app.UIFigure, 'numeric');
            app.xWindow.Limits = [0 20];
            app.xWindow.Value = 5;
            app.xStep = uieditfield(app.UIFigure, 'numeric');
            app.xStep.Value = 0.5;
            app.yWindow = uieditfield(app.UIFigure, 'numeric');
            app.yStep = uieditfield(app.UIFigure, 'numeric');
            app.zWindow = uieditfield(app.UIFigure, 'numeric');
            app.zWindow.Value = 5;
            app.zStep = uieditfield(app.UIFigure, 'numeric');
            app.zStep.Value = 0.5;
            app.yEditFieldLabel_2 = uilabel(app.UIFigure);
            app.yEditFieldLabel_2.Text = 'z';
            app.pressureThUp = uibutton(app.UIFigure, 'push');
            app.pressureThUp.ButtonPushedFcn = createCallbackFcn(app, @pressureThUpButtonPushed, true);
            app.pressureThUp.Text = '^';
            app.pressureThDown = uibutton(app.UIFigure, 'push');
            app.pressureThDown.ButtonPushedFcn = createCallbackFcn(app, @pressureThDownButtonPushed, true);
            app.pressureThDown.Text = 'v';
            app.pressureThLabel = uilabel(app.UIFigure);
            app.pressureThLabel.Text = 'Pressure Threshold: 0.5';
            app.focalXBox = uieditfield(app.UIFigure, 'numeric');
            app.focalXBox.ValueChangedFcn = createCallbackFcn(app, @focalXBoxValueChanged, true);
            app.focalYBox = uieditfield(app.UIFigure, 'numeric');
            app.focalYBox.ValueChangedFcn = createCallbackFcn(app, @focalYBoxValueChanged, true);
            app.focalZBox = uieditfield(app.UIFigure, 'numeric');
            app.focalZBox.ValueChangedFcn = createCallbackFcn(app, @focalZBoxValueChanged, true);
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.WaterTankCheckBox = uicheckbox(app.UIFigure);
            app.WaterTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaterTankCheckBoxValueChanged, true);
            app.WaterTankCheckBox.Text = 'Water Tank';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prescribe_cmp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end