classdef measurementGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SetSaveDirectoryButton    matlab.ui.control.Button
        saveDir                   matlab.ui.control.Label
        StartAngleEditFieldLabel  matlab.ui.control.Label
        StartAngleEditField       matlab.ui.control.NumericEditField
        FinalAngleEditFieldLabel  matlab.ui.control.Label
        FinalAngleEditField       matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel    matlab.ui.control.Label
        StepSizeEditField         matlab.ui.control.NumericEditField
        AnglesdegLabel            matlab.ui.control.Label
        ctAxes                    matlab.ui.control.UIAxes
        TextArea                  matlab.ui.control.TextArea
        SkullPositionLabel        matlab.ui.control.Label
        LoadCTScanButton          matlab.ui.control.Button
    end


    methods (Access = private)
    
        function [img, header,slice] = loadCt(app,ctDir)
            if ctDir(end) ~= '\' && ctDir(end) ~= '/'
                ctDir(end+1) = '\';
            end
            [img,header,dimL,dimP,dimS] = loadDicomDir(ctDir);
            slice = findCtSlice(app,img);
            res = [header{1}.PixelSpacing];
            res(3) = header{1}.SliceThickness;
            imagesc('xdata',[1,size(img,3)]*res(3),'ydata',[1,size(img,2)]*res(2),'cData',squeeze(img(slice,:,:)),'Parent',app.ctAxes,[1000,1.2e3])
            cmap = colormap(app.ctAxes,'bone');
            axis(app.ctAxes,'tight')
            axis(app.ctAxes,'equal')
            xlabel(app.ctAxes,'mm')
            ylabel(app.ctAxes,'mm')
        end
        
        function slice = findCtSlice(app,img)
            slice = 256;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\Users\Verasonics\Desktop\Taylor\Code\verasonics\lib\gui_lib');
            loadCt(app,'C:\Users\Verasonics\Desktop\Taylor\CTData\13841\20190905\DICOM\CTScan\');
        end

        % Button pushed function: LoadCTScanButton
        function LoadCTScanButtonPushed(app, event)
            dcmDir = uigetdir('C:\Users\Verasonics\Desktop\Taylor\CTData\13841\20190905\');
            loadCt(app,dcmDir);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SetSaveDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SetSaveDirectoryButton.Text = 'Set Save Directory';
            app.saveDir = uilabel(app.UIFigure);
            app.saveDir.Text = 'C:\Users\Verasonics\Desktop\Taylor';
            app.StartAngleEditFieldLabel = uilabel(app.UIFigure);
            app.StartAngleEditFieldLabel.Text = 'Start Angle';
            app.StartAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalAngleEditFieldLabel = uilabel(app.UIFigure);
            app.FinalAngleEditFieldLabel.Text = 'Final Angle';
            app.FinalAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalAngleEditField.Value = 90;
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.Value = 1;
            app.AnglesdegLabel = uilabel(app.UIFigure);
            app.AnglesdegLabel.Text = 'Angles (deg)';
            app.ctAxes = uiaxes(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'TX'};
            app.SkullPositionLabel = uilabel(app.UIFigure);
            app.SkullPositionLabel.Text = 'Skull Position';
            app.LoadCTScanButton = uibutton(app.UIFigure, 'push');
            app.LoadCTScanButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCTScanButtonPushed, true);
            app.LoadCTScanButton.Text = 'Load CT Scan';
        end
    end

    methods (Access = public)

        % Construct app
        function app = measurementGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end